classdef BeltApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MenuProject                matlab.ui.container.Menu
        MenuProjectNew             matlab.ui.container.Menu
        MenuProjectOpen            matlab.ui.container.Menu
        MenuProjectRecent          matlab.ui.container.Menu
        MenuProjectClose           matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        GridLayoutTopLeft          matlab.ui.container.GridLayout
        projectnameLabel           matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        Tab1                       matlab.ui.container.Tab
        UITable                    matlab.ui.control.Table
        RightPanel                 matlab.ui.container.Panel
        MainPanel                  matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        MainTab                    matlab.ui.container.TabGroup
        MainTabHome                matlab.ui.container.Tab
        HomeHTML                   matlab.ui.control.HTML
        BusyMsg                    matlab.ui.control.Label
        MainTabProject             matlab.ui.container.Tab
        ProjectHTML                matlab.ui.control.HTML
        MainTabSegmentation        matlab.ui.container.Tab
        SegmentationHTML           matlab.ui.control.HTML
        MainTabArtifactCorrection  matlab.ui.container.Tab
        BeltArtifactCorrectionComponent  BeltArtifactCorrectionComponent
        MainTabDeconvolution       matlab.ui.container.Tab
        DeconvolutionHTML          matlab.ui.control.HTML
        MainTabGraph               matlab.ui.container.Tab
        MainTabExport              matlab.ui.container.Tab
        LogPanel                   matlab.ui.container.Panel
        LogTextArea                matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        project; 
    end

    properties (Access = private)
        segment_raw % the segmented raw data that is send to the artifact correction component
        current_cfg % the cfg struct that is send to the artifact correction component
        selected_segment % the segment_nr and pp_nr of the currently highlighted segment
        busyMsg_counter = 1; % keep track of how many dots to append to the message
        busyMsg_msg % Description
        busyMsg_wait = false;
    end

    methods (Access = public)
        
        %% CALLBACKS
        function OpenProject(app, filename)

            % load the project data
            load(filename, "-mat", "project");

            % store the project in the app variable
            app.project = project; %#ok<ADPROPLC>

            % Show the project name in the title bar
            app.UIFigure.Name = ['Breda Experience Lab Toolbox     -== ', app.project.project_name, ' project ==-'];
            app.projectnameLabel.Text =  app.project.project_name;

            % populate the project tabel with the participant data
            app.populate_participant_table();

            % add this project to the 'recent' list in the
            % prefences file
            app.add_recent();

            % % Update the project details on the project html page
            % app.UpdateProjectInfo();

             % open the project details tab
            app.MainTab.SelectedTab = app.MainTabProject;
            
        end

        % Update the project details on the project html page
        function UpdateProjectInfo(app)
            % iniitialize variables
            segment_name(1:6)    = "";
            nof_segmented(1:6)   = 0;
            nof_cleaned(1:6)     = 0;
            nof_deconvolved(1:6) = 0;
            % get the numbers
            for segment_i = 1:app.project.nof_segments
                segment_name(segment_i) = app.project.segment(segment_i).name;
                nof_segmented(segment_i) = sum(app.project.segment(segment_i).segmented(:));
                nof_cleaned(segment_i) = sum(app.project.segment(segment_i).artifact_corrected(:));
                nof_deconvolved(segment_i) = sum(app.project.segment(segment_i).deconvolved(:));
            end
            %send the data to the html page
            app.ProjectHTML.Data = struct(...
                "ProjectName", app.project.project_name,...
			    "ProjectDir", app.project.project_directory,...
			    "Condition1", segment_name(1),...
			    "Condition2", segment_name(2),...
			    "NofSegmented1", nof_segmented(1),...
			    "NofSegmented2", nof_segmented(2),...
			    "NofCleaned1", nof_cleaned(1),...
			    "NofCleaned2", nof_cleaned(2),...
			    "NofDeconvolved1", nof_deconvolved(1),...
			    "NofDeconvolved2", nof_deconvolved(2)...
            );

        end


        function OpenRecentProject(app, recent_index)
            % open the recent project according to the selected index 
            pref = ['recent', num2str(recent_index)];

            if ispref('belt', pref)
                % get the project name, dir, and date from the preferences
                recent = getpref('belt', pref);

                % read the project data
                % recent(2) is the project directory
                % recent(1) is the project name
                full_file = fullfile(recent(2), append('project_', string(recent(1)), '.belt'));

                % load the project and show the project data
                app.OpenProject(full_file);

                % write a message in the log
                msg = ['recent project ''', app.project.project_name, ''' opened'];
                app.AppendLog(msg);

            end
        end

        % the index of the recent menu item is stored as event.Source.Position
       function OpenRecentProjectFromMenu(app, event)
            app.OpenRecentProject(event.Source.Position);
        end

         % the index of the recent menu item is stored as event.HTMLEventData
        function OpenRecentProjectFromHTML(app, event)
            app.OpenRecentProject(event.HTMLEventData);
        end

        % save the project struct
        function SaveProject(app)
            % save the project struct as a matlab datafile
            path_filename = fullfile(app.project.project_directory, ['project_' app.project.project_name '.belt']);
            project = app.project;
            save(path_filename, 'project');

        end

        % do artifact correction
        function ArtifactCorrection(app, cfg)

            % First check whether data was provided
            if isfield(cfg, 'pp_nr') && cfg.pp_nr == 0
                % no data provided, show an empty artifact correction window
                app.BeltArtifactCorrectionComponent.ClearData();
            end

            % check input
            if ~isfield(app.project, 'project_name')
                error('The project struct has no project_name field. I cannot create a project bookkeeping file without it.');
            end
            if ~isfield(app.project, 'project_directory')
                error('The project struct has no project_directory field. I cannot create a project bookkeeping file without it.');
            end
            if ~isfield(app.project, 'raw_data_directory')
                error('The project struct has no raw_data_directory field. Run create_new_project() first.');
            end
            if ~isfield(app.project, 'processed_data_directory')
                error('The project struct has no processed_data_directory field. Run create_new_project() first.');
            end
            if ~isfield(cfg, 'segment_nr')
                error('Provide a cfg.segment_nr');
            end
            if ~isfield(cfg, 'pp_nr')
                error('Provide a cfg.pp_nr');
            end
            if ~isfield(cfg, 'handle_already_cleaned_segments')
                cfg.handle_already_cleaned_segments = 'skip';
            end

            % set the parameters for the artifact detection algorithm
            if ~isfield(cfg, 'timwin')
                cfg.timwin = 20;
            end
            if ~isfield(cfg, 'threshold')
                cfg.threshold = 5;
            end
            if ~isfield(cfg, 'default_solution')
                cfg.default_solution = 'linear';
            end

            % for readability of the code
            pp_nr = cfg.pp_nr;
            pp_label = app.project.pp_labels{pp_nr};
            segment_nr = cfg.segment_nr;
            segment_name = app.project.segment(segment_nr).name;

            if ~isfield(cfg, 'segment_identifier')
                cfg.segment_identifier = ['Segment: ', segment_name, ', participant: ', pp_label];
            end

            % check whether artifact correction is even possible and needed
            [answer, msg] = artifact_correction_is_possible(cfg, app.project);
            app.AppendLog(msg);
            if ~answer % artifact correction is not possible or not needed
                return; % skip the rest of the function
            end

            % open the artifact correction tab
            app.MainTab.SelectedTab = app.MainTabArtifactCorrection;
            
            % Let the user know that the file is being loaded
           % msg = sprintf("Loading data of participant %s (condition %s) ", pp_label, segment_name);
            msg = sprintf("Loading data");
            app.showBusyMessage(msg);

            % load the segmented raw data
            path_filename = fullfile(app.project.processed_data_directory, ['segment_raw_' segment_name  '_' pp_label '.mat']);
            load(path_filename, 'segment_raw'); % loads segment_raw

            % store some data for later processing
            app.segment_raw = segment_raw;
            app.current_cfg = cfg;

            % highlight the segment in the participant table
            app.highlight_segment();

            % get potential artifacts
            cfg.artifacts = get_potential_artifacts(cfg, segment_raw);
            
            % open the artifact correction window to get the artifact corrected data
            % corrected_data = open_artifact_correction_window(cfg, segment_raw); % opens the app in a seperate window 
            
            % wait until the busy message is done
            while(app.busyMsg_wait)
                pause(0.01);% wait;
            end

            % show the raw data and potential artifacts in the the artifact correction component
            cfg.time = segment_raw.time;
            app.BeltArtifactCorrectionComponent.showData(cfg, segment_raw.conductance_raw);

        end

        % find the next segment/pp for artifact correction
        function next_cfg = FindNextSegmentToClean(app, current_cfg) 
            pp_nr = current_cfg.pp_nr;           % the current participant number 
            segment_nr = current_cfg.segment_nr; % the current segment number
            nof_pps = length(app.project.pp_labels); % the number of participants
            nof_segments = app.project.nof_segments; % the number of segments in the project

            still_searching = true; % indicate that we are searching for the next segment

            while still_searching
                % move to the next pp_nr within the current segment
                if pp_nr < nof_pps % if it was not the last participant
                    pp_nr = pp_nr + 1;
                else % it was the last participant of this segment
                    % see if we can move to the next segment
                    if segment_nr < nof_segments % if it was not the last segment
                        segment_nr = segment_nr + 1;
                        pp_nr = 1;
                    else % we reached the lat pp of the last segment
                        % TODO :: ask to continue the search at pp 1, segment 1
                        current_cfg.pp_nr = 0;
                        current_cfg.segment_nr = 0;
                        still_searching = false;
                    end
                end
                % check whether artifact correction is possible for this segment 
                current_cfg.pp_nr = pp_nr;
                current_cfg.segment_nr = segment_nr;
                [answer, msg] = artifact_correction_is_possible(current_cfg, app.project);
                app.AppendLog(msg);
                if answer % we found the next segment
                    still_searching = false;
                end
            end

            % return the new pp_nr and segment_nr
            current_cfg = rmfield(current_cfg, 'segment_identifier');
            next_cfg = current_cfg; 

        end


        % segment (cut) the raw conductance data according to start and end times of each condition
        % TODO: for now this just segments all pp, all conditions in one go. Is there a need for a more subtle approach?
        function Segmentation(app, cfg)

            % open the segmentation tab
            app.MainTab.SelectedTab = app.MainTabSegmentation;
            
            nof_pps = length(app.project.pp_labels);
            nof_pp_segments = nof_pps * app.project.nof_segments;
            
            % segment all conditions
            for segment_i = 1:app.project.nof_segments
                segment_name = app.project.segment(segment_i).name;
                % Show the correct segment in the participant data table
                app.TabGroup.SelectedTab = app.TabGroup.Children(segment_i);

                % segment data of all pps
                for pp_i = 1:nof_pps
                    pp_label = app.project.pp_labels{pp_i};
                    cfg = [];
                    cfg.segment_nr = segment_i;
                    cfg.pp_nr = pp_i;
                    cfg.fsample = 128; % provide the sampling frequency for Shimmer data, this is ignored for Empatica data (default = 128)
                    cfg.handle_already_segmented_data = 'skip'; % 'skip|ask|redo'
                    [app.project, msg] = belt_get_data_segment(cfg, app.project);

                    % Let the user know that the segmentation is in progress
                    nof_pp_segments_done = 0;
                    for segment_ii = 1:app.project.nof_segments % NOTE the use of the double i to avoid disrupting the parent for loop
                        nof_pp_segments_done = nof_pp_segments_done + sum(app.project.segment(segment_ii).segmented(:));
                    end
                    progress_value = nof_pp_segments_done / nof_pp_segments * 100;
                    % update the Segmentation html page
                    app.SegmentationHTML.Data = struct(...
                        "SegmentationSegment", ['Now segmenting the data of condition ', segment_name, ', participant: ',pp_label],...
			            "SegmentationProgress", ['<label><progress value="', num2str(progress_value), '" max="100" width="600px"></progress></label>'],...
			            "SegmentationStatus", 'Processing ...'...
                    );
                    drawnow();% update the graphics

                    % update the table
                    app.populate_participant_table();

                    % write a message in the log
                    app.AppendLog(msg);
                end
            end

            % Segmentation is complete
            app.SegmentationHTML.Data = struct(...
                "SegmentationSegment", ['Segmentation is complete'],...
                "SegmentationProgress", ['<label><progress value="', num2str(progress_value), '" max="100" width="600px"></progress></label>'],...
                "SegmentationStatus", 'You can now proceed with cleaning the data.<br>Click a box in the Clean column in the table on the left to start the Artifact Correction procedure for that data segment.'...
                );


        end


        % deconvolve (split) the conductance data into its phasic and tonic part
        function Deconvolution(app, cfg)

            % First check whether data was provided
            if isfield(cfg, 'pp_nr') && cfg.pp_nr == 0
                % no data provided, show an empty deconvolution window
                app.ShowEmptyDeconvolutionTab();
            end

            % check input
            if ~isfield(app.project, 'project_name')
                error('The project struct has no project_name field. I cannot create a project bookkeeping file without it.');
            end
            if ~isfield(app.project, 'project_directory')
                error('The project struct has no project_directory field. I cannot create a project bookkeeping file without it.');
            end
            if ~isfield(app.project, 'processed_data_directory')
                error('The project struct has no processed_data_directory field. Run create_new_project() first.');
            end
            if ~isfield(cfg, 'segment_nr')
                error('Provide a cfg.segment_nr');
            end
            if ~isfield(cfg, 'pp_nr')
                error('Provide a cfg.pp_nr');
            end
            if ~isfield(cfg, 'conductance')
                cfg.conductance = 'conductance_artifact_corrected';
            end
            if ~isfield(cfg, 'handle_already_deconvolved_segments')
                cfg.handle_already_deconvolved_segments = 'skip';
            end
            if ~isfield(cfg, 'tempdir')
                cfg.tempdir = fullfile(app.project.project_directory, '\Temp'); % temporary directory for datafiles
            end

            % for readability of the code
            pp_nr = cfg.pp_nr;
            pp_label = app.project.pp_labels{pp_nr};
            segment_nr = cfg.segment_nr;
            segment_name = app.project.segment(segment_nr).name;

            % check whether deconvolution is even possible and needed
            [answer, msg] = deconvolution_is_possible(cfg, app.project);
            app.AppendLog(msg);
            if ~answer % deconvolution is not possible or not needed
                return; % skip the rest of the function
            end

            % create the temp folder, if needed
            if ~exist(cfg.tempdir, "dir")
                % the folder does not exist
                [status, msg, msgID] = mkdir(cfg.tempdir); % create it
                tempfoldercreated = true; % take note that we created the folder. Remove it when we are done
            else
                tempfoldercreated = false; % folder was already there, so leave it.
            end

            % open the deconvolution tab
            app.MainTab.SelectedTab = app.MainTabDeconvolution;
            
            % Let the user know that the file is being loaded
            progress_value = 0;
            app.DeconvolutionHTML.Data = struct(...
                "DeconvolveSegment", ['Processing the data of participant: ', pp_label, ', condition ', segment_name],...
			    "DeconvolveProgress", ['<label><progress value="', num2str(progress_value), '" max="100" width="600px"></progress></label>'],...
			    "DeconvolveStatus", 'Loading data ...'...
            );
            drawnow();% update the graphics

            % load the data
            path_filename = fullfile(app.project.processed_data_directory, ['segment_artifact_corrected_' segment_name '_' pp_label '.mat']);
            load(path_filename, 'segment_artifact_corrected');

            % Let the user know that the data is being resampled
            progress_value = 10;
            app.DeconvolutionHTML.Data = struct(...
                "DeconvolveSegment", ['Processing the data of participant: ', pp_label, ', condition ', segment_name],...
			    "DeconvolveProgress", ['<label><progress value="', num2str(progress_value), '" max="100" width="600px"></progress></label>'],...
			    "DeconvolveStatus", 'Resampling the data to 4Hz...'...
            );
            drawnow();% update the graphics

            % set resampling TODO: make this a setting in preferences
            cfg.resample = 'yes';
            cfg.fsample = 4;

            if isfield(cfg, 'resample') && strcmp(cfg.resample, 'yes') && isfield(cfg, 'fsample')
                % resample the data before deconvolution
                cfg_resample = [];
                cfg_resample.fsample = cfg.fsample;
                segment_artifact_corrected = resample_generic(cfg_resample,  segment_artifact_corrected);
            end

            % add the zscored conductance
            segment_artifact_corrected.conductance_artifact_corrected_z = normalize(segment_artifact_corrected.conductance_artifact_corrected);
            cfg.conductance_z   = 'conductance_artifact_corrected_z';

            % Let the user know that deconvolution has started
            progress_value = 20;
            app.ProjectHTML.Data = struct(...
                "DeconvolveSegment", ['Processing the data of participant: ', pp_label, ', condition ', segment_name],...
			    "DeconvolveProgress", ['<label><progress value="', num2str(progress_value), '" max="100" width="600px"></progress></label>'],...
			    "DeconvolveStatus", 'Deconvolution in progress (this may take a while) ...'...
            );
            drawnow();% update the graphics

            % do the deconvolution thing
            segment_deconvolved = deconvolve_eda(cfg, segment_artifact_corrected);

            % Let the user know that deconvolution has started
            progress_value = 80;
            app.DeconvolutionHTML.Data = struct(...
                "DeconvolveSegment", ['Processing the data of participant: ', pp_label, ', condition ', segment_name],...
			    "DeconvolveProgress", ['<label><progress value="', num2str(progress_value), '" max="100" width="600px"></progress></label>'],...
			    "DeconvolveStatus", 'Deconvolution complete...'...
            );
            drawnow();% update the graphics

            % do some reorganizing and renaming
            [segment_deconvolved.conductance_phasic] = segment_deconvolved.phasic;
            [segment_deconvolved.conductance_phasic_z] = segment_deconvolved.phasic_z;
            [segment_deconvolved.conductance_tonic] = segment_deconvolved.tonic;
            [segment_deconvolved.conductance_tonic_z] = segment_deconvolved.tonic_z;
            segment_deconvolved = rmfield(segment_deconvolved,'phasic');
            segment_deconvolved = rmfield(segment_deconvolved,'phasic_z');
            segment_deconvolved = rmfield(segment_deconvolved,'tonic');
            segment_deconvolved = rmfield(segment_deconvolved,'tonic_z');
            segment_deconvolved = rmfield(segment_deconvolved,'conductance_artifact_corrected');
            segment_deconvolved = rmfield(segment_deconvolved,'conductance_artifact_corrected_z');
            segment_deconvolved = orderfields(segment_deconvolved,...
                {'pp_label', ...
                'segment_name', ...
                'datatype',...
                'orig', ...
                'initial_time_stamp', ...
                'initial_time_stamp_mat', ...
                'fsample', ...
                'timeoff', ...
                'event', ...
                'analysis', ...
                'time', ...
                'eventchan', ...
                'conductance_phasic', ...
                'conductance_phasic_z', ...
                'conductance_tonic', ...
                'conductance_tonic_z' ...
                });

            % Let the user know that saving has started
            progress_value = 90;
            app.DeconvolutionHTML.Data = struct(...
                "DeconvolveSegment", ['Processing the data of participant: ', pp_label, ', condition ', segment_name],...
			    "DeconvolveProgress", ['<label><progress value="', num2str(progress_value), '" max="100" width="600px"></progress></label>'],...
			    "DeconvolveStatus", 'Deconvolution complete. Saving the data ...'...
            );
            drawnow();% update the graphics

            % save the deconvolved data
            msg = save_deconvolved_data(cfg, app.project, segment_deconvolved);

            % update the table
            app.populate_participant_table();

            % Let the user know that saving has started
            progress_value = 100;
            app.DeconvolutionHTML.Data = struct(...
                "DeconvolveSegment", ['Processing the data of participant: ', pp_label, ', condition ', segment_name],...
			    "DeconvolveProgress", ['<label><progress value="', num2str(progress_value), '" max="100" width="600px"></progress></label>'],...
			    "DeconvolveStatus", 'Saving complete!'...
            );
            drawnow();% update the graphics

            % Provide some feedback
            app.AppendLog(msg);

            % update the bookkeeping of the project
            app.project.segment(segment_nr).deconvolved(pp_nr) = true;

            % save the project bookkeeping
            save_project(app.project);

            % if we created a temporary folder, we should clean up
            if tempfoldercreated
                try
                    rmdir(cfg.tempdir, 's'); % this fails sometimes, don't know why
                catch ME
                    app.AppendLog('Removing the Temp directory failed.')
                end
            end

            % % wait until the busy message is done
            % while(app.busyMsg_wait)
            %     pause(0.01);% wait;
            % end

            % % find the next segment to deconvolve
            % next_cfg = app.FindNextSegmentToDeconvolve(app.current_cfg);
            % 
            % % store the new numbers in the cfg
            % app.current_cfg      = next_cfg;
            % 
            % % highlight the segment in the participant table
            % app.highlight_segment();
            % 
            % % start artifact deconvolution of the next segment
            % app.Deconvolution(app.current_cfg); % ====================== call a function from within that function, is that possible?
            % 
        end


        % Save deconvolved data
        function SaveDeconvolvedData(app, event)
            % process the artifact corrected data when the [Save] button is pressed

            % Let the user know that the file is being saved
            app.showBusyMessage("Saving");

            % save the artifact corrected segment
            app.segment_raw.conductance_artifact_corrected = app.BeltArtifactCorrectionComponent.solution;
            [app.project, msg] = save_artifact_corrected_data(app.current_cfg, app.project, app.segment_raw);

            % clear the previous data
            app.BeltArtifactCorrectionComponent.ClearData();

            % update the table
            app.populate_participant_table();

            % provide feedback
            app.AppendLog(msg);

            % wait until the busy message is done
            while(app.busyMsg_wait)
                pause(0.01);% wait;
            end

            % find the next segment to clean
            next_cfg = app.FindNextSegmentToClean(app.current_cfg);

            % store the new numbers in the cfg
            app.current_cfg      = next_cfg;

            % highlight the segment in the participant table
            app.highlight_segment();

            % start artifact correction of the next segment
            app.ArtifactCorrection(app.current_cfg);
            
        end


        % find the next segment/pp for artifact correction
        function next_cfg = FindNextSegmentToDeconvolve(app, current_cfg) 
            % pp_nr = current_cfg.pp_nr;           % the current participant number 
            % segment_nr = current_cfg.segment_nr; % the current segment number
            % nof_pps = length(app.project.pp_labels); % the number of participants
            % nof_segments = app.project.nof_segments; % the number of segments in the project
            % 
            % still_searching = true; % indicate that we are searching for the next segment
            % next_pp_nr = 0;
            % next_segment_nr = 0;
            % 
            % while still_searching
            %     % move to the next pp_nr within the current segment
            %     if pp_nr < nof_pps % if it was not the last participant
            %         pp_nr = pp_nr + 1;
            %     else % it was the last participant of this segment
            %         % see if we can move to the next segment
            %         if segment_nr < nof_segments % if it was not the last segment
            %             segment_nr = segment_nr + 1;
            %             pp_nr = 1;
            %         else % we reached the lat pp of the last segment
            %             % TODO :: ask to continue the search at pp 1, segment 1
            %             current_cfg.pp_nr = 0;
            %             current_cfg.segment_nr = 0;
            %             still_searching = false;
            %         end
            %     end
            %     % check whether artifact correction is possible for this segment 
            %     current_cfg.pp_nr = pp_nr;
            %     current_cfg.segment_nr = segment_nr;
            %     [answer, msg] = artifact_correction_is_possible(current_cfg, app.project);
            %     app.AppendLog(msg);
            %     if answer % we found the next segment
            %         still_searching = false;
            %     end
            % end
            % 
            % % return the new pp_nr and segment_nr
            % current_cfg = rmfield(current_cfg, 'segment_identifier');
            % next_cfg = current_cfg; 
            % 
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % toggle include variable (true|false)
        function ToggleInclude(app, cfg)
            pp_nr = cfg.pp_nr;
            pp_label = app.project.pp_labels{pp_nr};
            segment_nr = cfg.segment_nr;
            segment_name = app.project.segment(segment_nr).name;

            if app.project.segment(segment_nr).include(pp_nr) % if true
                include_status = 'false'; % set to false
                app.project.segment(segment_nr).include(pp_nr) = 0;
            else % if false
                include_status = 'true'; % set to true
                app.project.segment(segment_nr).include(pp_nr) = 1;
            end       
            % save the project
            app.SaveProject();
            % update the table
            app.populate_participant_table();
            % provide feedback
            msg = sprintf('Segment name  = ''%s'', participant ''%s'': include set to ''%s''.', segment_name, pp_label, include_status);
            app.AppendLog(msg);
        end

        %% VISUALS

        %% UI ELEMENTS


        %% CALCULATIONS


    end
    
    methods (Access = private)
        
        
        % Add event to the log
        function AppendLog(app, msg)
            if ~isempty(msg)
                t = datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss');
                t = string(t);
                new_text = sprintf('[%s] %s\n', t, msg);
                app.LogTextArea.Value = [app.LogTextArea.Value(:)', {new_text}];
                scroll(app.LogTextArea, "bottom");
            end
        end


        % populate the participant data table
        function populate_participant_table(app)
            % update the tabs
            app.update_tabs();

            % create a table from the project data
            for segment_i = 1:app.project.nof_segments
                pp = app.project.pp_labels;
                seg = logical(app.project.segment(segment_i).segmented);
                art = logical(app.project.segment(segment_i).artifact_corrected);
                dec = logical(app.project.segment(segment_i).deconvolved);
                inc = logical(app.project.segment(segment_i).include);
                t = table(pp, seg, art, dec, inc);

                % fill the table with the contents of table t
                app.UITable(segment_i).Data = t;        

                % show the changes
                app.UITable(segment_i).Visible = 'on';
                
                % refresh the gui to make these changes visible
                drawnow();

                % add the select table cell callback
                app.UITable(segment_i).CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);

            end

            % Update the project details on the project html page
            app.UpdateProjectInfo();

        end

        % update the tabs
        function update_tabs(app)
            % note the number of tabs
            nof_tabs = size(app.TabGroup.Children, 1);
            % for each segment, create a tab
            for segment_i = 1:app.project.nof_segments
                if segment_i > nof_tabs
                    % this tab does not exist yet, so create it.
                    tabgroup = app.TabGroup;
                    t = uitab(tabgroup, 'Title', app.project.segment(segment_i).name);
                    % copy the table of tab 1 to add it to this new tab.
                    % using matlab function copyobj(h, p); h = obj to copy, p = parent of the new object
                    app.UITable(segment_i) = copyobj(app.UITable(1), app.TabGroup.Children(segment_i));
                    % add callback function
                    app.UITable(segment_i).CellSelectionCallback = createCallbackFcn(app, @ParticipantsTableCellSelection, true);

                else
                    % this tab does exist, so rename it
                    app.TabGroup.Children(segment_i).Title = app.project.segment(segment_i).name;
                end
            end
        end

        % append the current project to the list of recent projects
        function add_recent(app)

            % add the current project as the most recent
            t = datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss');
            t = string(t);
            setpref('belt', 'recent1', {app.project.project_name, app.project.project_directory, t});


            % get the current recent list, and store in the next recent spots
            offset = 1; % move all previous items 1 spot down
            for recent_i = 1:5
                old_pref = ['recent', num2str(recent_i)];
                new_pref = ['recent', num2str(recent_i + offset)];

                if ispref('belt', old_pref) % if a recent project exists
                    recent_temp = getpref('belt', old_pref); % get the project name, dir, and date
                    % then check whether it is not the current project
                    if strcmp(recent_temp(1), app.project.project_name) && strcmp(recent_temp(2), app.project.project_directory)
                        % same project as current one, so skip this to avoid duplicates
                        % if we skipped one, the remaining recent projects
                        % can keep their place
                        offset = max(offset-1, 0);
                    else
                        % save the previous recent projects
                        setpref('belt', new_pref, recent_temp);
                    end
                end
            end

            % update the recent menu
           app.show_recent_projects();

            % remove preferences of belt app === for testing ===
            % rmpref('belt', 'recent1');
            % rmpref('belt', 'recent2');
            % rmpref('belt', 'recent3');
            % rmpref('belt', 'recent4');
            % rmpref('belt', 'recent5');
            % rmpref('belt');
        end

        % show the recent projects in the menu and on the home tab
        function show_recent_projects(app)
            
            % First remove the previous recent projects list
            delete(app.MenuProjectRecent.Children);
            html_recent = {'recent1','recent2','recent3','recent4','recent5'};
            
            % the add them again, based on the recent projects list 
            % that is stored in the preferences
            for recent_i = 1:5
                pref = ['recent', num2str(recent_i)];

                if ispref('belt', pref) % if a recent project exists

                    % get the project name, dir, and date
                    recent = getpref('belt', pref); 
                    % add it to the recent menu
                    mitem = uimenu(app.MenuProjectRecent);
                    mitem.MenuSelectedFcn = createCallbackFcn(app, @OpenRecentProjectFromMenu, true);
                    mitem.Text = string(recent(1));

                    % add it to the home tab
                    % === TODO ===
                    html_recent(recent_i) = recent(1);
                end
            end

            app.HomeHTML.Data = html_recent;
        end

        function highlight_segment(app)
            % remove the previous highlight
            if isfield(app.selected_segment, 'segment_nr')
                removeStyle(app.UITable(app.selected_segment.segment_nr));
            end

            if app.current_cfg.pp_nr > 0
                % then highlight the new segment
                s1 = uistyle;
                s1.FontWeight = 'Bold';
                s1.BackgroundColor = [0.7, 0.7, 0.7];
                app.UITable(app.current_cfg.segment_nr).addStyle(s1, 'row', app.current_cfg.pp_nr);
    
                % remember which segment_nr and pp_nr is highlighted
                app.selected_segment.segment_nr = app.current_cfg.segment_nr;
                app.selected_segment.pp_nr = app.current_cfg.pp_nr;
            end
        end

        function showBusyMessage(app, msg)
            % create a timer
            t = timer;

            % set call back functions
            t.StartFcn = @app.BusyMsgTimerStart; % when timer starts
            t.TimerFcn = @app.BusyMsgTimerUpdate; % after each period
            t.StopFcn  = @app.BusyMsgTimerStop; % when timer stops
            
            % specify timing properties
            t.period = 0.2; % seconds
            t.StartDelay = 0.2;
            t.TasksToExecute = 4; % number of times the update function is called
            t.ExecutionMode = 'fixedSpacing';

            % store the message in a global variable
            app.busyMsg_msg = msg;

            % wait until the timer has done it's stuff before displaying
            % the data/results
            app.busyMsg_wait = true;

            % start the timer
            t.start;
 
        end

        function BusyMsgTimerStart(app, mTimer, ~)
            % move the message display to the current tab
            app.BusyMsg.Parent = app.MainTab.SelectedTab;
            % set the counter for displaying the ...
            app.busyMsg_counter = 0;
            % append dots to the message
            msg_with_dots = sprintf("%s", app.busyMsg_msg);
            % update the mesage display with the requested text
            app.BusyMsg.Text = msg_with_dots;
            % make the message display visible
            app.BusyMsg.Position = [247,220,171,17];
            app.BusyMsg.Visible = "on";
            drawnow();
        end

        function BusyMsgTimerUpdate(app, mTimer, ~)
            % increase the dot counter
            app.busyMsg_counter = app.busyMsg_counter + 1;
            % update the message, add a dot to the end;
            msg_with_dots = sprintf("%s%s", app.busyMsg_msg, repmat(' .', 1, app.busyMsg_counter));
            app.BusyMsg.Text = msg_with_dots;
            drawnow();
        end

        function BusyMsgTimerStop(app, mTimer, ~)
            % hide the message
            app.BusyMsg.Position = [50,302,171,17];
            app.BusyMsg.Visible = "off";
            % indicate that waiting is not needed anymore
            app.busyMsg_wait = false;
            % stop the timer
            mTimer.stop;
            drawnow();
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, cfg)
          
            % Hide the busy doing what message
            app.BusyMsg.Visible = "off";

            % set the title bar
            app.UIFigure.Name = 'Breda Experience Lab Toolbox';

            % show the recent projects in the menubar and on the home tab
            app.show_recent_projects();

            %Make app fullscreen
            drawnow;
            % app.UIFigure.WindowState = 'maximized';

            % === FOR TESTING :: load an existing project at startup to
            % populate the pp table
            % load('C:\projects\PSV\project_PSV.belt', "-mat");
            
            % add the project name to the title bar
            % app.UIFigure.Name = [app.UIFigure.Name, ' - ', project.project_name];


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Event received function: HomeHTML
        function HomeHTMLEventReceived(app, event)
            disp('HomeHTMLEventReceived');
            eventName = event.HTMLEventName;
            eventData = event.HTMLEventData;
            if strcmp(eventName, 'OpenProject')
                app.OpenExistingProject(event);
            elseif strcmp(eventName, 'NewProject')
                app.OpenNewProject(event);
            elseif strcmp(eventName, 'RecentProject')
                app.OpenRecentProjectFromHTML(event);
            end
        end

        % Menu selected function: MenuProjectOpen
        function OpenExistingProject(app, event)

                % Let the user select a .belt project file
                [file,location] = uigetfile('*.belt', 'Open a BELT project', 'C:\');
                
                if file % a file was selected

                    % read the project data
                    full_file = fullfile(location, file);

                    % load the project and show the project data
                    app.OpenProject(full_file);

                    % write a message in the log
                    msg = ['existing project ''', app.project.project_name, ''' opened'];
                    app.AppendLog(msg);

                end
            
        end

        % Menu selected function: MenuProjectNew
        function OpenNewProject(app, event)
                app.UIFigure.Name = 'New project';
                app.AppendLog('opening a new project');

                cfg = [];
                cfg.show_input_window = true;
                app.project = [];
                
                % check and create directories, and create the project struct
                app.project = create_new_project(cfg, app.project);

                % Add the relevant participant data to the project struct
                app.project = add_participant_data(cfg, app.project);

                % add the bookkeeping part to the project
                for segment_i = 1:app.project.nof_segments
                    app.project.segment(segment_i).segmented          = zeros(size(app.project.pp_labels));
                    app.project.segment(segment_i).artifact_corrected = zeros(size(app.project.pp_labels));
                    app.project.segment(segment_i).deconvolved        = zeros(size(app.project.pp_labels));
                    app.project.segment(segment_i).include            = ones(size(app.project.pp_labels));
                end

                % save the new project
                app.SaveProject();

                % load the project and show the project data
                path_filename = fullfile(app.project.project_directory, ['project_' app.project.project_name '.belt']);
                app.OpenProject(path_filename);

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            % Determine what was clicked on ...
            %   segment_name :: read form the title of the tab = event.Source.Parent.Title 
            %   pp_nr        :: is the row number = event.Indices(1)
            %   action       :: is the column number event.Indices(2) (1=pp label, 2=segment, 3=artifact correct, 4=deconvolve, 5=include)

            % for testing
            % msg = sprintf('Clicked on Segment ''%s'', Participant ''%s'', Column ''%s''.', event.Source.Parent.Title, app.project.pp_labels{indices(1)}, event.Source.ColumnName{indices(2)});
            % app.AppendLog(msg);

            % clear the previous data
            app.BeltArtifactCorrectionComponent.ClearData();

            % determine the segment_nr
            segment_name = event.Source.Parent.Title;
            segment_nr = 0;
            for segment_i = 1:app.project.nof_segments % check all segments
                if strcmp(app.project.segment(segment_i).name, segment_name) % compare segment names
                    segment_nr = segment_i; % segment found
                    break;
                end
            end
            % determine the participant nr
            pp_nr = indices(1); % = row nr

            % create the cfg struct
            cfg = [];
            cfg.pp_nr = pp_nr;
            cfg.segment_nr = segment_nr;


            switch indices(2) % column nr
                case 1 % pp label

                case 2 % segment
                    app.Segmentation(cfg);
                case 3 % artifact correction                    
                    app.ArtifactCorrection(cfg);
                case 4 % deconvolution
                    app.Deconvolution(cfg);
                case 5 % include
                    % toggle the include value (true|false)
                    app.ToggleInclude(cfg);

            end    

        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if strcmp(app.MainTab.SelectedTab.Title, 'Artifact Correction')
                cursorXY = app.UIFigure.CurrentPoint;
                % app.UIFigure.Name = ['(', num2str(cursorXY(1)), ', ', num2str(cursorXY(2)), ')'];
    
                % pass on the relative cursor position to the component
                % NOTE: WE MAY NOT NEED TO PASS ON THE COORDINATES,
                %       ONLY TO CALL THEMOUSEMOVE FUNCTION
                cursorX = cursorXY(1) - app.RightPanel.Position(1) - app.MainPanel.Position(1) - app.MainTab.Position(1) - app.MainTabArtifactCorrection.Position(1) - app.BeltArtifactCorrectionComponent.Position(1);
                cursorY = cursorXY(2) - app.RightPanel.Position(2) - app.MainPanel.Position(2) - app.MainTab.Position(2) - app.MainTabArtifactCorrection.Position(2) - app.BeltArtifactCorrectionComponent.Position(2);
                
                cursorXY(1) = cursorX;
                cursorXY(2) = cursorY;
                
                Pointertype = app.BeltArtifactCorrectionComponent.MouseMove(cursorXY);
                app.UIFigure.Pointer = Pointertype;
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if strcmp(app.MainTab.SelectedTab.Title, 'Artifact Correction')
                app.BeltArtifactCorrectionComponent.MouseUp();
            end
        end

        % Callback function: BeltArtifactCorrectionComponent
        function SkipArtifactCorrection(app, event)
            % the current segment will not be saved
            % the next available segment will be opened for artifact correction

            % clear the previous the data
            app.BeltArtifactCorrectionComponent.ClearData();

            % provide feedback
            pp_label = app.project.pp_labels{app.current_cfg.pp_nr};
            segment_name = app.project.segment(app.current_cfg.segment_nr).name;
            msg = sprintf('Data of participant %s segment %s was skipped.', pp_label, segment_name);
            app.AppendLog(msg);
            
            % find the next segment to clean
            next_cfg = app.FindNextSegmentToClean(app.current_cfg);

            % store the new numbers in the cfg
            app.current_cfg      = next_cfg;

            % highlight the segment in the participant table
            app.highlight_segment();

            % start artifact correction of the next segment
            app.ArtifactCorrection(app.current_cfg);
            
        end

        % Callback function: BeltArtifactCorrectionComponent
        function StopArtifactCorrection(app, event)
            % indicate no pp is selected
            app.current_cfg.pp_nr = 0;

            % remove the highlight of the current segment in the participant table
            app.highlight_segment();

            % remove the data from the artifact correction tab
            app.BeltArtifactCorrectionComponent.ClearData();
            
            % show the project tab
            app.MainTab.SelectedTab = app.MainTabProject;

        end

        % Callback function: BeltArtifactCorrectionComponent
        function SaveArtifactCorrectedData(app, event)
            % process the artifact corrected data when the [Save] button is pressed

            % Let the user know that the file is being saved
            app.showBusyMessage("Saving");

            % save the artifact corrected segment
            app.segment_raw.conductance_artifact_corrected = app.BeltArtifactCorrectionComponent.solution;
            [app.project, msg] = save_artifact_corrected_data(app.current_cfg, app.project, app.segment_raw);

            % update the table
            app.populate_participant_table();

            % provide feedback
            app.AppendLog(msg);

            % wait until the busy message is done
            while(app.busyMsg_wait)
                pause(0.01);% wait;
            end

            % clear the previous data
            app.BeltArtifactCorrectionComponent.ClearData();

            % find the next segment to clean
            next_cfg = app.FindNextSegmentToClean(app.current_cfg);

            % store the new numbers in the cfg
            app.current_cfg      = next_cfg;

            % highlight the segment in the participant table
            app.highlight_segment();

            % start artifact correction of the next segment
            app.ArtifactCorrection(app.current_cfg);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {595, 595};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {269, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'BeltApp';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.MenuProject = uimenu(app.UIFigure);
            app.MenuProject.Text = 'Project';
            app.MenuProjectNew = uimenu(app.MenuProject);
            app.MenuProjectNew.MenuSelectedFcn = createCallbackFcn(app, @OpenNewProject, true);
            app.MenuProjectNew.Accelerator = 'N';
            app.MenuProjectNew.Text = 'New';
            app.MenuProjectOpen = uimenu(app.MenuProject);
            app.MenuProjectOpen.MenuSelectedFcn = createCallbackFcn(app, @OpenExistingProject, true);
            app.MenuProjectOpen.Accelerator = 'O';
            app.MenuProjectOpen.Text = 'Open';
            app.MenuProjectRecent = uimenu(app.MenuProject);
            app.MenuProjectRecent.Text = 'Recent';
            app.MenuProjectClose = uimenu(app.MenuProject);
            app.MenuProjectClose.Text = 'Close';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Segment';
            app.UITable = uitable(app.Tab1);
            app.UITable.ColumnName = {'Participant'; 'Cut'; 'Clean'; 'Split'; 'Use'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.GridLayoutTopLeft = uigridlayout(app.GridLayout3);
            app.projectnameLabel = uilabel(app.GridLayoutTopLeft);
            app.projectnameLabel.Text = 'project name';
            app.RightPanel = uipanel(app.GridLayout);
            app.MainPanel = uipanel(app.RightPanel);
            app.MainPanel.Title = 'Main';
            app.GridLayout4 = uigridlayout(app.MainPanel);
            app.LogPanel = uipanel(app.GridLayout4);
            app.LogPanel.Title = 'Log';
            app.LogTextArea = uitextarea(app.LogPanel);
            app.LogTextArea.Value = {'Starting log ...'};
            app.MainTab = uitabgroup(app.GridLayout4);
            app.MainTabHome = uitab(app.MainTab);
            app.MainTabHome.Title = 'Home';
            app.BusyMsg = uilabel(app.MainTabHome);
            app.BusyMsg.Text = 'What is going on ...?';
            app.HomeHTML = uihtml(app.MainTabHome);
            app.HomeHTML.HTMLSource = 'BeltAppHome.html';
            app.HomeHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @HomeHTMLEventReceived, true);
            app.MainTabProject = uitab(app.MainTab);
            app.MainTabProject.Title = 'Project';
            app.ProjectHTML = uihtml(app.MainTabProject);
            app.ProjectHTML.HTMLSource = 'BeltAppProject.html';
            app.MainTabSegmentation = uitab(app.MainTab);
            app.MainTabSegmentation.Title = 'Segmentation';
            app.SegmentationHTML = uihtml(app.MainTabSegmentation);
            app.SegmentationHTML.HTMLSource = 'BeltAppSegmentation.html';
            app.MainTabArtifactCorrection = uitab(app.MainTab);
            app.MainTabArtifactCorrection.Title = 'Artifact Correction';
            app.BeltArtifactCorrectionComponent = BeltArtifactCorrectionComponent(app.MainTabArtifactCorrection);
            app.BeltArtifactCorrectionComponent.ArtifactDragging = [];
            app.BeltArtifactCorrectionComponent.SaveArtifactCorrectedDataFcn = createCallbackFcn(app, @SaveArtifactCorrectedData, true);
            app.BeltArtifactCorrectionComponent.SkipArtifactCorrectionFcn = createCallbackFcn(app, @SkipArtifactCorrection, true);
            app.BeltArtifactCorrectionComponent.StopArtifactCorrectionFcn = createCallbackFcn(app, @StopArtifactCorrection, true);
            app.MainTabDeconvolution = uitab(app.MainTab);
            app.MainTabDeconvolution.Title = 'Deconvolution';
            app.DeconvolutionHTML = uihtml(app.MainTabDeconvolution);
            app.DeconvolutionHTML.HTMLSource = 'BeltAppDeconvolution.html';
            app.MainTabGraph = uitab(app.MainTab);
            app.MainTabGraph.Title = 'Graph';
            app.MainTabExport = uitab(app.MainTab);
            app.MainTabExport.Title = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeltApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end