classdef BeltArtifactCorrectionComponent < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        ConclusionPanel                matlab.ui.container.Panel
        AcceptButton                   matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        ARTIFACTPanel                  matlab.ui.container.Panel
        ArtifactYesButton              matlab.ui.control.Button
        ArtifactNoButton               matlab.ui.control.Button
        SolutionOptionsPanel           matlab.ui.container.Panel
        SolutionsDropDown              matlab.ui.control.DropDown
        SolutionLabel                  matlab.ui.control.Label
        SolutionsCustomValue           matlab.ui.control.NumericEditField
        customvalueEditFieldLabel      matlab.ui.control.Label
        ArtifactDurationPanel          matlab.ui.container.Panel
        Label                          matlab.ui.control.Label
        TimeTooltip                    matlab.ui.control.TextArea
        ShowtimevalueatcursorCheckBox  matlab.ui.control.CheckBox
        ArtifactEndValue               matlab.ui.control.NumericEditField
        EndEditFieldLabel              matlab.ui.control.Label
        ArtifactStartValue             matlab.ui.control.NumericEditField
        StartEditFieldLabel            matlab.ui.control.Label
        InfoPanel                      matlab.ui.container.Panel
        AllButton                      matlab.ui.control.Button
        SelectedArtifactNrLabel        matlab.ui.control.Label
        FocusButton                    matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
        PreviousButton                 matlab.ui.control.Button
        SelectedArtifactLabel          matlab.ui.control.Label
        WholeSegmentAxes               matlab.ui.control.UIAxes
        ArtefactValidationAxes         matlab.ui.control.UIAxes
    end

    properties (Access = public)
        ArtifactDragging % Keep track of artifact border being dragged
        ToolTip2 % Shows the time value of the cursor position
        addartifactapp % connection to the add artifact window
        artifacts % struct with all artifacts ('start','end','solution','custom')
        closeapplication = 0; % boolean value for the main application to return when set to true
        drawvalidationdata = 1;
        focusMaxTime % the highest time value of a focussed window
        focusMinTime % the lowest time value of a focussed window
        history % all previous artifacts structs. Every time a change is made, it will be appended to this list. Once the undo button is pressed, it will remove the latest entry, and overwrite artifacts with the current last one.
        mode = 'focus'; % whether the current mode is in 'focus'  or 'all', determining the current visualization
        original % array of the original data
        originalobject % the original data plot, later used for handling the brush
        prepostduration = 20;
        selectedartifact % index of selected artifact
        solution % array of the solution data
        time % float value for time
        timeResolution % distance between two timepoints
        visibleArtifactsRectangles % rectangles mapped on the axis
        visibleartifacts % array of 1s (true) and 0s (false) to keep track of which artifacts are visible
    end
    
    
    methods (Access = private)
        %% CALLBACKS

        % Callback when artifact is clicked on (NOTE: this is a public function in the app)
        function ClickArtifact(comp, object, eventdata)
            % if the cursor is over an artifact border
            if comp.ArtifactDragging.NearBorder
                % start dragging the artifact border
                comp.ArtifactDragging.BeingDragged = true;
            else
                % (de)select the artifact that is clicked on
                startpos = object.Position(1);
                endpos = object.Position(1) + object.Position(3);
                for i=1:length(comp.artifacts)
                    if startpos == comp.artifacts(i).starttime && endpos == comp.artifacts(i).endtime
                        ArtifactInteraction(comp,i);
                        break;
                    end
                end
            end
        end
        
        % Callback then original data axis is clicked on
        function ClickAxes(comp, object, eventdata)
            if comp.ArtifactDragging.NearBorder
                % start dragging the artifact border
                comp.ArtifactDragging.BeingDragged = true;
            else
                % deselect all artifacts
                comp.DeselectAllArtifacts();
            end
        end

        %% INTERACTIVITY

        % Interact with an Artifact
        function ArtifactInteraction(comp,artifactID)
            if comp.selectedartifact == artifactID
                % The selected artifact was deselected
                comp.selectedartifact = 0;
            else
                % an artifact that was not selected, is now selected
                comp.selectedartifact = artifactID;
            end
            comp.RedrawArtifacts();
            comp.ShowSelectedArtifactNr();
            comp.PlotVisuals();
        end

        % Deselect all artifacts
        function DeselectAllArtifacts(comp)
            comp.selectedartifact = 0;
            comp.SetActiveUIElements();
            comp.RedrawArtifacts();
            comp.ShowSelectedArtifactNr();
            comp.PlotVisuals();

        end


        % Delete selected artifact
        function RemoveArtifact(comp)

            % restore original conductance
            minx = find(comp.time==comp.artifacts(comp.selectedartifact).starttime); 
            maxx = find(comp.time==comp.artifacts(comp.selectedartifact).endtime);
            comp.solution(minx:maxx) = comp.original(minx:maxx);            
                    
            % remove the selected artifact from the list
            comp.artifacts(comp.selectedartifact)=[];
            % also remove from the visible artifacts list
            comp.SetVisibleArtifacts(comp.focusMinTime, comp.focusMaxTime);

            % show that the conductance has been restored
            comp.PlotVisuals();
            % pause for 0.5 second to give the user confirmation that the
            % artifact was removed. Then move to the next artifact
            pause(0.5);

            % select a new artifact
            comp.selectedartifact = comp.SelectClosestTo(comp.selectedartifact);

            % allow for undo
            comp.AddActionToHistory;

            % draw the new focus area (or all data)
            if strcmp(comp.mode,'focus')
                comp.FocusOnSelection();
            else
                comp.ShowAllData();
            end
            comp.SetActiveUIElements();
            comp.RedrawArtifacts();
            comp.ShowSelectedArtifactNr();
            comp.PlotVisuals();
        end

        function new_selection = SelectClosestTo(comp,index)
            nof_artifacts = length(comp.artifacts);
            if nof_artifacts >= index
                % select the next artifact in the list
                new_selection = index;
            else
                % select the last artifact in the list
                new_selection = nof_artifacts;
            end
        end


        % Reverts the current artifacts to the previous state. Only
        % considers remove, add, merge, solution and duration changes.
        % Selection is not taken into account right now
        function UndoAction(comp)
            if length(comp.history) > 1
                comp.artifacts = comp.history(length(comp.history)-1).artifacts;
                comp.history(length(comp.history)) = [];
            else
                % comp.UndoButton.Enable = 0; % === TODO :: ADD UNDO BUTTON
            end

            comp.CalculateSolution();
            comp.PlotVisuals();
            comp.RedrawArtifacts();
            comp.SetActiveUIElements();
        end
        
        % add current state to the history list and enable the undo button
        function AddActionToHistory(comp)
            comp.history(length(comp.history)+1).artifacts = comp.artifacts;
            % comp.UndoButton.Enable = true; % === TODO :: ADD UNDO BUTTON
        end


        % Change the duration of the selected artifact
        function DurationAction(comp)
            comp.artifacts(comp.selectedartifact).starttime = comp.ArtifactStartValue.Value;
            comp.artifacts(comp.selectedartifact).endtime   = comp.ArtifactEndValue.Value;
            comp.CalculateVisibleSolution();
            comp.RedrawArtifacts();
            comp.PlotVisuals();
        end

        % Disable add action, and open window for creating new artifact
        function NewArtifactAction(comp)
            if isprop(comp.originalobject,'BrushData') && any (comp.originalobject.BrushData)
                selected = comp.originalobject.BrushData;
                range = find(selected ==1);
                starttime = comp.time(min(range));
                endtime = comp.time(max(range));
                
                comp.originalobject.BrushData = 0;

                comp.ReturnNewArtifactAction(starttime, endtime);
            end
        end

        % Catch the new artifact action and update current
        function ReturnNewArtifactAction(comp,starttime,endtime)
            artifact = struct('starttime',starttime,'endtime',endtime,'solution','linear','custom',1);
            if isempty(comp.artifacts)
                comp.artifacts = artifact;
            else
                comp.artifacts(length(comp.artifacts)+1) = artifact;
            end
            comp.selectedartifact = length(comp.artifacts); % the new artifact is the last
            comp.SortArtifacts(); % then set the artifact in chronological order
            comp.CalculateSolution(); % calculate the solution of the artifact
            comp.AddActionToHistory; % make sure this can be uncone
            comp.SetVisibleArtifacts(comp.focusMinTime, comp.focusMaxTime); % add new artifact to the list of visible artifacts
            comp.RedrawArtifacts(); % show all rectangle of the visible artifacts
            comp.PlotVisuals();
            comp.ShowSelectedArtifactNr();
        end

        % Change solution on all selected artifacts
        function ChangeSolution(comp,solution)
            if comp.selectedartifact
                comp.artifacts(comp.selectedartifact).solution=solution;
                if strcmp(solution,'custom')
                    comp.artifacts(comp.selectedartifact).custom=comp.SolutionsCustomValue.Value;
                end
                comp.AddActionToHistory;    
                comp.CalculateVisibleSolution();
                comp.PlotVisuals();                
            end
        end

        % Select next artifact
        function SelectNextArtifact(comp)
            % comp.DeselectAllArtifacts();
            % Check whether there is a next artifact
            if length(comp.artifacts) > comp.selectedartifact
                comp.selectedartifact = comp.selectedartifact + 1; 
                %comp.artifacts(comp.selectedartifact).selected = 1;
                if strcmp(comp.mode,'focus')
                    comp.FocusOnSelection();
                else
                    comp.ShowAllData();
                end
            else % currently selected is the last
                %
                comp.selectedartifact = 0; 
                comp.ShowAllData();
            end
            comp.SetActiveUIElements();
            comp.RedrawArtifacts();
            comp.ShowSelectedArtifactNr();
            comp.PlotVisuals();
        end

        % Select previous artifact
        function SelectPreviousArtifact(comp)
            % comp.DeselectAllArtifacts();
            % Check whether there is a previous artifact
            if  comp.selectedartifact > 1
                comp.selectedartifact = comp.selectedartifact - 1;
                % comp.artifacts(comp.selectedartifact).selected = 1;
                if strcmp(comp.mode,'focus')
                    comp.FocusOnSelection();
                else
                    comp.ShowAllData();
                end
            else % currently selected is the last
                %
                comp.selectedartifact = 0;
                comp.ShowAllData();
            end
            comp.SetActiveUIElements();
            comp.RedrawArtifacts();
            comp.ShowSelectedArtifactNr();
            comp.PlotVisuals();
        end

        % Focus on the selected artifact areas
        function FocusOnSelection(comp)
            mintime = min(comp.time);
            maxtime = max(comp.time);
            
            if comp.selectedartifact
                if comp.artifacts(comp.selectedartifact).starttime - comp.prepostduration > mintime
                    mintime = comp.artifacts(comp.selectedartifact).starttime - comp.prepostduration;
                end
                if comp.artifacts(comp.selectedartifact).endtime + comp.prepostduration < maxtime
                    maxtime = comp.artifacts(comp.selectedartifact).endtime + comp.prepostduration;
                end
                mintime = clamp(mintime,min(comp.time),max(comp.time));
                maxtime = clamp(maxtime,min(comp.time),max(comp.time));
    
                % determine which artifacts are within the focus interval
                comp.SetVisibleArtifacts(mintime, maxtime);
                comp.FocusOnArea(mintime,maxtime);
                comp.mode = 'focus';
                % comp.PlotVisuals();                
            end
        end

        function SetVisibleArtifacts(comp, mintime, maxtime)
            % intialize array of zeros to set visible to false for each artifactPlotArtifactValidation
            visible_artifacts = zeros(1, length(comp.artifacts));
            % then check for each whether the time ofthe artifact falls
            % within the specified time window
            for artifact_i=1:length(comp.artifacts)
                if (comp.artifacts(artifact_i).starttime > mintime && comp.artifacts(artifact_i).starttime < maxtime) ||...
                        (comp.artifacts(artifact_i).endtime > mintime && comp.artifacts(artifact_i).endtime < maxtime)
                    visible_artifacts(artifact_i) = 1;
                end
            end
            comp.visibleartifacts = visible_artifacts;
        end

        % Show the entire data signal
        function ShowAllData(comp)
            comp.mode = 'all';
            mintime = min(comp.time);
            maxtime = max(comp.time);
            
            % all artifacts are visible
            comp.SetVisibleArtifacts(mintime, maxtime);
            comp.RedrawArtifacts();
            comp.FocusOnArea(mintime,maxtime);
            comp.PlotVisuals();
        end


        %% VISUALS
        % Reset the current visuals, and draw the latest visual state
        function PlotVisuals(comp)
            comp.PlotArtifactValidation();
            comp.PlotWholeSegment();
        end

         % Draw the Artifact validation area 
        function PlotArtifactValidation(comp)
            % clear the graph area
            cla(comp.ArtefactValidationAxes);
            % hold on = draw over the graph, keep what is already there
            hold(comp.ArtefactValidationAxes,'on');
            % draw the artifact intervals
            comp.PlotArtifacts();
            % draw the original data
            comp.originalobject = plot(comp.ArtefactValidationAxes,comp.time,comp.original, "Color", [0.3 0.3 0.3], "LineStyle", ":");
            % monitor whether the graph area is clicked on
            set(comp.ArtefactValidationAxes,'ButtonDownFcn',@comp.ClickAxes);
            % draw the artifact solution
            plot(comp.ArtefactValidationAxes,comp.time,comp.solution, "Color", [0, 0.4470, 0.7410], "LineStyle", "-")
            % hold off
            hold(comp.ArtefactValidationAxes,'off');
        end

         % Draw the hole segment area 
        function PlotWholeSegment(comp)
            % clear the graph area
            cla(comp.WholeSegmentAxes);
            % in the bottom graph area, draw the whole original data segment
            plot(comp.WholeSegmentAxes,comp.time,comp.solution)
            minx = min(comp.time);
            maxx = max(comp.time);
            miny = min(comp.solution);
            maxy = max(comp.solution);
            set(comp.WholeSegmentAxes,'XLim',[minx maxx]);

        end

        % Plot all rectangles where artifacts are suspected
        function PlotArtifacts(comp)
            for i=find(comp.visibleartifacts) % loop through the visible artifacts
                startpos = comp.artifacts(i).starttime;
                endpos = comp.artifacts(i).endtime;
                color = [.8 .8 .8 0.2];
                if comp.selectedartifact == i
                    color = [1 .8 .8 0.3];
                end
                comp.visibleArtifactsRectangles(i) = rectangle(comp.ArtefactValidationAxes,'Position',[startpos 0 endpos-startpos max(comp.original)],"FaceColor",color);
                set(comp.visibleArtifactsRectangles(i),'ButtonDownFcn',@comp.ClickArtifact);
            end
        end

        % Only recreate the artifact visuals
        function RedrawArtifacts(comp)
            if isobject(comp.visibleArtifactsRectangles)
                delete(comp.visibleArtifactsRectangles);   
            end
            comp.visibleArtifactsRectangles=[];
            comp.PlotArtifacts();
        end

        % Set the visuals to focus on a specific area
        function FocusOnArea(comp,minx,maxx)

            miny = min(comp.RetrieveSection(minx,maxx));
            maxy = max(comp.RetrieveSection(minx,maxx));

            comp.focusMinTime = minx;
            comp.focusMaxTime = maxx;

            set(comp.ArtefactValidationAxes,'XLim',[minx maxx],'YLim',[miny maxy]);
        end

        %% UI ELEMENTS

        % Set the enabled UI elements based on the selected artifacts
        function SetActiveUIElements(comp)

            % undo button === TODO :: ADD UNDO BUTTON ===
            % if (length(comp.history)) > 1
            %     comp.UndoButton.Enable = 1;
            % else
            %     comp.UndoButton.Enable = 0;                
            % end
            % focus, previous, next
            if ~isempty(comp.artifacts)
                comp.FocusButton.Enable = 1;
                comp.PreviousButton.Enable = 1;
                comp.NextButton.Enable = 1;
            else
                comp.FocusButton.Enable = 0;
                comp.PreviousButton.Enable = 0;
                comp.NextButton.Enable = 0;
            end
            if (comp.selectedartifact == length(comp.artifacts))
                comp.NextButton.Enable = 0;
            end
            if (comp.selectedartifact <= 1)
                comp.PreviousButton.Enable = 0;
            end
            % solutions
            if (comp.selectedartifact > 0)
                comp.SolutionsDropDown.Enable = 1;
                comp.ArtifactStartValue.Enable = 1;
                comp.ArtifactStartValue.Value = comp.artifacts(comp.selectedartifact).starttime;
                comp.ArtifactEndValue.Enable = 1;
                comp.ArtifactEndValue.Value = comp.artifacts(comp.selectedartifact).endtime;
                comp.ArtifactYesButton.Enable = 1;
                comp.ArtifactNoButton.Enable = 1;
                comp.SolutionsDropDown.Value = comp.artifacts(comp.selectedartifact).solution;
            else
                comp.SolutionsDropDown.Enable = 0;
                comp.ArtifactStartValue.Enable = 0;
                comp.ArtifactStartValue.Value = 0;
                comp.ArtifactEndValue.Enable = 0;
                comp.ArtifactEndValue.Value = 0;
                comp.ArtifactYesButton.Enable = 0;
                comp.ArtifactNoButton.Enable = 0;                
            end
        end

        %% CALCULATIONS

        function out = FindArtifactID(comp,starttime,endtime)
            out = 0;DeselectAllArtifacts
            for i=1:length(comp.artifacts)
                if comp.artifacts(i).starttime == starttime && comp.artifacts(i).endtime == endtime
                    out = i;
                end
            end
        end

        % Sort all artifacts based on their starttime
        function SortArtifacts(comp)
            if ~isempty(comp.artifacts)
                T=struct2table(comp.artifacts);
                [sorT, index] = sortrows(T,'starttime');
                comp.artifacts = table2struct(sorT);
                if comp.selectedartifact % an artifact was selected
                    % the selected number may have changed in the sorting
                    comp.selectedartifact = find(index == comp.selectedartifact);
                end
            end
        end

        % calculate how many artifacts there are in the application
        function ShowSelectedArtifactNr(comp)
            comp.SetActiveUIElements();
            if comp.selectedartifact 
                labeltext = mat2str(comp.selectedartifact);                  
            else
                labeltext = '-';
            end
            labeltext = [labeltext ' (of ' mat2str(length(comp.artifacts)) ')'];
            comp.SelectedArtifactNrLabel.Text = labeltext;
        end

        % Grab the original, and change all artifact ranges to their solution
        function CalculateSolution(comp)
            % start fresh with the original data
            comp.solution(:) = comp.original(:);
            % replace all artifact intervals with the chosen solution
            for i=1:length(comp.artifacts)
                comp.SetArtifactOutcome(comp.artifacts(i));
            end
        end

        % calculate the solution of a single artifact
        function CalculateVisibleSolution(comp)
            
            % start fresh with the original data
            minx = find(comp.time==comp.focusMinTime);
            maxx = find(comp.time==comp.focusMaxTime);
            comp.solution(minx:maxx) = comp.original(minx:maxx);

            % then replace visible artifact intervals with the chosen solution
            for i=find(comp.visibleartifacts)
                comp.SetArtifactOutcome(comp.artifacts(i));
            end
        end

        % Get a section of the data from the original axis
        function out  = RetrieveSection(comp,starttime,endtime)
            tRange = find(comp.time>=starttime & comp.time<=endtime);
            min(tRange);
            max(tRange);
            out= comp.original(min(tRange):max(tRange));
        end

        % Get the solution to the artifact based on the artifact solution value
        function SetArtifactOutcome(comp,artifact)

            startpos = find(comp.time==artifact.starttime);
            endpos = find(comp.time==artifact.endtime);

            artifactarray = comp.original(startpos:endpos);

            if strcmp(artifact.solution,"linear")
                artifactarray = linspace(artifactarray(1),artifactarray(end),length(artifactarray));
            end
            if strcmp(artifact.solution,"NaN")
                artifactarray(2:end-1) =  NaN(1, length(artifactarray)-2); % start and endpoints remain original
            end
            if strcmp(artifact.solution,"startpos")
                artifactarray = artifactarray(1) * ones(1, length(artifactarray));
            end
            if strcmp(artifact.solution,"endpos")
                artifactarray = artifactarray(end) * ones(1, length(artifactarray));
            end
            if strcmp(artifact.solution,"custom")
                artifactarray = artifact.custom * ones(1, length(artifactarray));
            end
            if strcmp(artifact.solution,"spline")
                % the spline function generates a smooth line between points
                % to replace the artifact with a new curve
                % we need some data before and after to create the spline
                % here we use 2 datapoints to anker the spline to
                spline_ankers = 2;

                % create new start and end positions, 
                newstartpos = startpos + 1 - spline_ankers; % startpos + 1 to keep startpos original
                newendpos   = endpos - 1   + spline_ankers; % endpos - 1 to keep endpos original

                % check whether these do not exceed the timeline of the data
                newstartpos = clamp(newstartpos, 1, length(comp.time));
                newendpos = clamp(newendpos, 1, length(comp.time));

                % determine the adjusted anker width for the spline
                spline_anker_start = startpos - newstartpos; % ?? what happens when this is 0??
                spline_anker_end   = newendpos - endpos;

                % get the orignal data for the artifact plus spline ankers
                x = comp.time(newstartpos:newendpos);
                y = comp.original(newstartpos:newendpos);

                % replace the artifact by NaNs, keep the spline ankers
                % Note that these are the original ankers, not the adjusted ones. So, 
                % if the ankers have been adjusted, now artifact data is used 
                % to generate the smooth line, which is not ideal.
                % y(spline_ankers+1:length(y)-spline_ankers) = NaN;
                x_ankers = x;
                x_ankers(spline_ankers+1:length(x)-spline_ankers) = [];
                y_ankers = y;
                y_ankers(spline_ankers+1:length(y)-spline_ankers) = [];

                % use spline() to generate a smooth line connecting the start and end ankers
                result = spline(x_ankers,y_ankers, x);

                % cut out the interval of the artifact
                artifactarray = result(spline_anker_start+1:length(y)-spline_anker_end);

            end
            comp.solution(startpos:endpos) = artifactarray;
        end
    end
    
    methods (Access = public)
        
        function showData(comp, cfg, data)

            % Show which segment and participant this is
            if isfield(cfg, 'segment_identifier')
                comp.ArtefactValidationAxes.Title.String = cfg.segment_identifier;
            else
                comp.ArtefactValidationAxes.Title.String = 'Warning: unknown segment';
            end

            % set how much time before and after the selected artifact to show
            if isfield(cfg, 'artifactprepostvisualization')
                comp.prepostduration = cfg.artifactprepostvisualization;
            end

            % set the time variable
            if isfield(cfg, 'time')
                comp.time = cfg.time;
            else
                comp.time = 0:length(data)-1;
            end
            comp.timeResolution = comp.time(2)-comp.time(1);

            % create an array of the potential artifacts
            if isfield(cfg, 'artifacts')
                if ~isempty(cfg.artifacts)
                    artifacts_new(length(cfg.artifacts)) = struct('starttime',0,'endtime',0,'solution','linear','custom',1);
                    for i=1:length(cfg.artifacts)
                        artifact = cfg.artifacts(i);
                        if ~isfield(artifact, 'solution')
                            artifact.solution = 'linear';
                        end
                        if ~isfield(artifact, 'custom')
                            artifact.custom = 1;
                        end
                        artifacts_new(i) = artifact;
                    end
                    comp.artifacts = artifacts_new;
                    comp.history(1).artifacts = comp.artifacts;
                end
            end

            % the skin conductance signal 
            comp.original = data; % the raw data
            comp.solution = data; % the cleaned data

            % sort artifacts 
            comp.SortArtifacts();

            % use the default solution to do an initial cleaning of the data
            comp.CalculateSolution();

            % select the first artifact if there are any artifacts provided
            if ~isempty(comp.artifacts)
                comp.selectedartifact = 1;
                % Select and focus on the first artifact
                comp.FocusOnSelection();
            else
                % no artifacts found, show all data
                comp.selectedartifact = 0;
                comp.ShowAllData();
            end

            % keep track of artifact border being dragged
            comp.ArtifactDragging = struct('BeingDragged',false,'Index',0, 'NearBorder', false, 'LeftBorder',true, 'BorderChanged', false, 'DurationChanged', false); % Keep track of artifact border being dragged

            % set UI Elements
            comp.SetActiveUIElements();
            % set initial axes
            comp.PlotVisuals();
            comp.ShowSelectedArtifactNr();
        end
        
        % Handle mouse movements. This function is called by the parent app
        % input parameter is the cursor position
        % output is the type of mouse pointer
        function Pointertype = MouseMove(comp, cursorXY)
            % pos = comp.ArtefactValidationAxes.CurrentPoint;
            % comp.ArtefactValidationAxes.Title.String = ['MouseMove received with x = ', num2str(x), ', y = ' num2str(y), ' position in component: x=', num2str(pos(1, 1)), ', y=', num2str(pos(1, 2))];
            
            Pointertype = "arrow";

            % get the mouse coordinates
            % cursorXY = app.BeltArtifactCorrectionAppUI.CurrentPoint; 
            cursorTime = comp.ArtefactValidationAxes.CurrentPoint(1,1);

            % determine wheter mouse is over the artifact validation area
            isInAxes = cursorXY(1) >= (comp.Position(1) + comp.ArtefactValidationAxes.InnerPosition(1)) && ...
                cursorXY(1) <= (comp.Position(1) + sum(comp.ArtefactValidationAxes.InnerPosition([1,3]))) && ...
                cursorXY(2) >= comp.ArtefactValidationAxes.InnerPosition(2) && ...
                cursorXY(2) <= sum(comp.ArtefactValidationAxes.InnerPosition([2,4]));

            if isInAxes
                % if the "show time at cursor" checkbox is checked, place a
                % small textbox near the cursor to show the time value at the cursor
                if comp.ShowtimevalueatcursorCheckBox.Value == 1
                    % show the time value tooltip
                    mouseX = comp.ArtefactValidationAxes.CurrentPoint(1, 1)+10;
                    mouseY = comp.ArtefactValidationAxes.CurrentPoint(1, 2)+10;
                    set(comp.TimeTooltip, 'Value',...
                        sprintf('t=%.2f', comp.ArtefactValidationAxes.CurrentPoint(1,1)));
                    set(comp.TimeTooltip, 'Position', [mouseX, mouseY, 60, 25]);
                    set(comp.TimeTooltip, 'Visible', true);
                    % comp.TimeTooltip.Parent =  comp.BeltArtifactCorrectionAppUI;
                else
                    % hide the time value tooltip
                    set(comp.TimeTooltip, 'Value', '')
                    set(comp.TimeTooltip, 'Visible', false)
                end

                % if an artifact border is in the process of being dragged
                if isfield(comp.ArtifactDragging, 'BeingDragged') % the field does not exist before data is loaded
                    if comp.ArtifactDragging.BeingDragged
                        if comp.ArtifactDragging.LeftBorder
                            % update the starttime of the artifact
                            % check whether it is still smaller than the endtime
                            if (comp.ArtifactEndValue.Value - cursorTime) > comp.timeResolution
                                comp.ArtifactStartValue.Value = cursorTime;
                                comp.ArtifactStartValueValueChanged();
                                comp.ArtifactDragging.DurationChanged = true;
                            end
                        else
                            % update the endtime of the artifact
                            % check whether it is still larger than the starttime
                            if (cursorTime - comp.ArtifactStartValue.Value) > comp.timeResolution
                                comp.ArtifactEndValue.Value = cursorTime;
                                comp.ArtifactEndValueValueChanged();
                                comp.ArtifactDragging.DurationChanged = true;
                            end
                        end

                    else % if not currently being dragged

                        % test whether the cursor is near the border of the selected artifact interval
                        isNearBorder = false;
                        nearnessLimit = comp.timeResolution;
                        % check which artifact is selected
                        selectedArtifactIndex = comp.selectedartifact;

                        % check whether the cursor is near the borders of the selected artifact
                        if selectedArtifactIndex > 0
                            startpos = comp.artifacts(selectedArtifactIndex).starttime; % left border
                            endpos = comp.artifacts(selectedArtifactIndex).endtime; % right border
                            if abs(cursorTime - startpos) <= nearnessLimit
                                isNearBorder = true;
                                comp.ArtifactDragging.LeftBorder = true;
                            elseif abs(cursorTime - endpos) <= nearnessLimit
                                isNearBorder = true;
                                comp.ArtifactDragging.LeftBorder = false;
                            end
                            if isNearBorder
                                % change the cursor to the left-right dubbel arrow <->
                                % to indicate that the border can be dragged
                                % comp.BeltArtifactCorrectionAppUI.Pointer = "left";
                                Pointertype = "left";
                                % mark that cursor is near the border
                                comp.ArtifactDragging.NearBorder = true;
                                % mark which artifact this is the border of
                                comp.ArtifactDragging.Index = selectedArtifactIndex;
                            else
                                % change cursor back to the default arrow
                                % Pointertype = "arrow";
                                % and mark that cursor is not near a border
                                comp.ArtifactDragging.NearBorder = false;
                            end
                        end
                    end
                end
            else
                % make sure the pointer is an arrow when the cursor leaves the artifact validation area
                % Pointertype = "arrow";
                % and mark that cursor is not near a border
                comp.ArtifactDragging.NearBorder = false; % TODO: this is a functionality choice, test whether this is optimal
            end
        
        end % MouseMove()

        % mouse up event is passed on by the parent app
        function MouseUp(comp)
            if isfield(comp.ArtifactDragging, 'BeingDragged') % the field does not exist before data is loaded
                if comp.ArtifactDragging.BeingDragged
                    comp.ArtifactDragging.BeingDragged = false;
                    if comp.ArtifactDragging.DurationChanged == true
                        comp.AddActionToHistory;
                        comp.ArtifactDragging.DurationChanged = false;
                    end
                end
            end
        end

    end % methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
          
            comp.ArtifactDragging = struct('BeingDragged',false,'Index',0, 'NearBorder', false, 'LeftBorder',true, 'BorderChanged', false, 'DurationChanged', false); % Keep track of artifact border being dragged
            drawnow;

        end

        % Button pushed function: AllButton
        function AllButtonPushed(comp, event)
            comp.ShowAllData();
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(comp, event)
            comp.SelectPreviousArtifact();
        end

        % Button pushed function: FocusButton
        function FocusButtonPushed(comp, event)
            comp.FocusOnSelection();
        end

        % Button pushed function: NextButton
        function NextButtonPushed(comp, event)
            comp.SelectNextArtifact();
        end

        % Value changed function: ArtifactStartValue
        function ArtifactStartValueValueChanged(comp, event)

            % get the value from the input box
            value = comp.ArtifactStartValue.Value;

            % adjust this value to the closest match in the list of timepoints
            value = interp1(comp.time, comp.time,value,'nearest');
            comp.ArtifactStartValue.Value = value;

            % update the duration and redraw
            comp.DurationAction();

            % if this was not the result of a dragging action, update the history list
            if comp.ArtifactDragging.BeingDragged == false
                comp.AddActionToHistory;
            end
            
        end

        % Value changed function: ArtifactEndValue
        function ArtifactEndValueValueChanged(comp, event)

            % get the value from the input box
            value = comp.ArtifactEndValue.Value;

            % adjust this value to the closest match in the list of timepoints
            value = interp1(comp.time, comp.time,value,'nearest');
            comp.ArtifactEndValue.Value = value;

            % update the duration and redraw
            comp.DurationAction();

            % if this was not the result of a dragging action, update the history list
            if comp.ArtifactDragging.BeingDragged == false
                comp.AddActionToHistory;
            end
            
        end

        % Value changed function: ShowtimevalueatcursorCheckBox
        function ShowtimevalueatcursorCheckBoxValueChanged(comp, event)
            % value = comp.ShowtimevalueatcursorCheckBox.Value;
            % nothing to do, check the checkbox directly when it is needed             
        end

        % Value changed function: SolutionsDropDown
        function SolutionsDropDownValueChanged(comp, event)
            value = comp.SolutionsDropDown.Value;
            comp.ChangeSolution(value);
        end

        % Value changed function: SolutionsCustomValue
        function SolutionsCustomValueValueChanged(comp, event)
            value = comp.SolutionsCustomValue.Value;
            comp.ChangeSolution('custom');
        end

        % Button pushed function: ArtifactYesButton
        function ArtifactYesButtonPushed(comp, event)
            comp.SelectNextArtifact();
        end

        % Button pushed function: ArtifactNoButton
        function ArtifactNoButtonPushed(comp, event)
            comp.RemoveArtifact();
        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(comp, event)
            comp.closeapplication = 1;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(comp, event)
            comp.solution = comp.original;
            comp.closeapplication = 1;
        end

        % Button down function: ArtefactValidationAxes
        function ArtefactValidationAxesButtonDown(comp, event)
            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 1028 598];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.ArtefactValidationAxes = uiaxes(comp.GridLayout);
            title(comp.ArtefactValidationAxes, 'Artifact Validation')
            xlabel(comp.ArtefactValidationAxes, 'Time (s)')
            ylabel(comp.ArtefactValidationAxes, 'Conductance (\muS)')
            zlabel(comp.ArtefactValidationAxes, 'Z')
            comp.ArtefactValidationAxes.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @ArtefactValidationAxesButtonDown, true);
            comp.WholeSegmentAxes = uiaxes(comp.GridLayout);
            title(comp.WholeSegmentAxes, 'Corrected conductance whole segment')
            xlabel(comp.WholeSegmentAxes, 'Time (s)')
            ylabel(comp.WholeSegmentAxes, 'Conductance (\muS)')
            zlabel(comp.WholeSegmentAxes, 'Z')
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.InfoPanel = uipanel(comp.GridLayout2);
            comp.InfoPanel.Title = 'Info';
            comp.SelectedArtifactLabel = uilabel(comp.InfoPanel);
            comp.SelectedArtifactLabel.Text = 'Selected Artifact:';
            comp.PreviousButton = uibutton(comp.InfoPanel, 'push');
            comp.PreviousButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @PreviousButtonPushed, true);
            comp.PreviousButton.Text = '<';
            comp.NextButton = uibutton(comp.InfoPanel, 'push');
            comp.NextButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @NextButtonPushed, true);
            comp.NextButton.Text = '>';
            comp.FocusButton = uibutton(comp.InfoPanel, 'push');
            comp.FocusButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @FocusButtonPushed, true);
            comp.FocusButton.Text = 'Focus';
            comp.SelectedArtifactNrLabel = uilabel(comp.InfoPanel);
            comp.SelectedArtifactNrLabel.Text = '0 of (0)';
            comp.AllButton = uibutton(comp.InfoPanel, 'push');
            comp.AllButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @AllButtonPushed, true);
            comp.AllButton.Text = 'All';
            comp.ArtifactDurationPanel = uipanel(comp.GridLayout2);
            comp.ArtifactDurationPanel.Title = 'Artifact Duration';
            comp.StartEditFieldLabel = uilabel(comp.ArtifactDurationPanel);
            comp.StartEditFieldLabel.Text = 'Start';
            comp.ArtifactStartValue = uieditfield(comp.ArtifactDurationPanel, 'numeric');
            comp.ArtifactStartValue.ValueDisplayFormat = '%.2f';
            comp.ArtifactStartValue.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ArtifactStartValueValueChanged, true);
            comp.EndEditFieldLabel = uilabel(comp.ArtifactDurationPanel);
            comp.EndEditFieldLabel.Text = 'End';
            comp.ArtifactEndValue = uieditfield(comp.ArtifactDurationPanel, 'numeric');
            comp.ArtifactEndValue.ValueDisplayFormat = '%.2f';
            comp.ArtifactEndValue.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ArtifactEndValueValueChanged, true);
            comp.ShowtimevalueatcursorCheckBox = uicheckbox(comp.ArtifactDurationPanel);
            comp.ShowtimevalueatcursorCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ShowtimevalueatcursorCheckBoxValueChanged, true);
            comp.ShowtimevalueatcursorCheckBox.Text = 'Show time value at cursor';
            comp.TimeTooltip = uitextarea(comp.ArtifactDurationPanel);
            comp.TimeTooltip.Visible = 'off';
            comp.TimeTooltip.Value = {'t=0.00'};
            comp.Label = uilabel(comp.ArtifactDurationPanel);
            comp.Label.Text = '';
            comp.SolutionOptionsPanel = uipanel(comp.GridLayout2);
            comp.SolutionOptionsPanel.Title = 'Solution Options';
            comp.customvalueEditFieldLabel = uilabel(comp.SolutionOptionsPanel);
            comp.customvalueEditFieldLabel.Text = 'custom value';
            comp.SolutionsCustomValue = uieditfield(comp.SolutionOptionsPanel, 'numeric');
            comp.SolutionsCustomValue.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SolutionsCustomValueValueChanged, true);
            comp.SolutionLabel = uilabel(comp.SolutionOptionsPanel);
            comp.SolutionLabel.Text = 'method';
            comp.SolutionsDropDown = uidropdown(comp.SolutionOptionsPanel);
            comp.SolutionsDropDown.Items = {'linear', 'spline', 'NaN', 'startpos', 'endpos', 'custom'};
            comp.SolutionsDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SolutionsDropDownValueChanged, true);
            comp.SolutionsDropDown.Value = 'linear';
            comp.ARTIFACTPanel = uipanel(comp.GridLayout2);
            comp.ARTIFACTPanel.Title = 'ARTIFACT?';
            comp.ArtifactNoButton = uibutton(comp.ARTIFACTPanel, 'push');
            comp.ArtifactNoButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ArtifactNoButtonPushed, true);
            comp.ArtifactNoButton.Icon = fullfile(pathToMLAPP, 'icons', 'thumbup.png');
            comp.ArtifactNoButton.Text = 'NO ARTIFACT';
            comp.ArtifactYesButton = uibutton(comp.ARTIFACTPanel, 'push');
            comp.ArtifactYesButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ArtifactYesButtonPushed, true);
            comp.ArtifactYesButton.Icon = fullfile(pathToMLAPP, 'icons', 'thumbdown.png');
            comp.ArtifactYesButton.Text = 'ARTIFACT';
            comp.ConclusionPanel = uipanel(comp.GridLayout2);
            comp.ConclusionPanel.Title = 'Conclusion';
            comp.CancelButton = uibutton(comp.ConclusionPanel, 'push');
            comp.CancelButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @CancelButtonPushed, true);
            comp.CancelButton.Text = 'Cancel';
            comp.AcceptButton = uibutton(comp.ConclusionPanel, 'push');
            comp.AcceptButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @AcceptButtonPushed, true);
            comp.AcceptButton.Text = 'Accept';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end