classdef BeltGPSArtifactCorrectionComponent < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout2                 matlab.ui.container.GridLayout
        AnimatePanel                matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        SlowerButton                matlab.ui.control.Button
        FasterButton                matlab.ui.control.Button
        FastForwardButton           matlab.ui.control.Button
        PlayButton                  matlab.ui.control.Button
        StopAnimationButton         matlab.ui.control.Button
        PauseButton                 matlab.ui.control.Button
        RewindButton                matlab.ui.control.Button
        FindPanel                   matlab.ui.container.Panel
        AccuracyLimit               matlab.ui.control.NumericEditField
        meterEditField_2Label       matlab.ui.control.Label
        JumpLimit                   matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        AccuracyButton              matlab.ui.control.Button
        JumpsButton                 matlab.ui.control.Button
        ZoomPanel                   matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        ZoomInButton                matlab.ui.control.Button
        ZoomOutButton               matlab.ui.control.Button
        ZoomHomeButton              matlab.ui.control.Button
        ActionsPanel_2              matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        DeleteSelectionButton       matlab.ui.control.Button
        InterpolateSelectionButton  matlab.ui.control.Button
        UndoButton                  matlab.ui.control.Button
        RedoButton                  matlab.ui.control.Button
        ConclusionPanel             matlab.ui.container.Panel
        GridLayout6                 matlab.ui.container.GridLayout
        StopButton                  matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        SkipButton                  matlab.ui.control.Button
        SelectionPanel              matlab.ui.container.Panel
        RestoreAllButton            matlab.ui.control.Button
        RestoreSelectionButton      matlab.ui.control.Button
        SelectNoneButton            matlab.ui.control.Button
        SelectAllButton             matlab.ui.control.Button
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        SaveArtifactCorrectedData
        SkipArtifactCorrection
        StopArtifactCorrection
    end

    properties (Access = private)
        GPSAxes  matlab.graphics.axis.GeographicAxes % Area to plot the GPS data
    end
    
    properties (Access = public)
        ControlKeyDown logical = false; % is the control key currently pressed
        DragMapStartMapCenter % location of the map canter when dragging started
        DragMapStartXY % start location of dragging the map in XY pixels
        GPSAxesDragging string = 'none'; % is mouse dragging in progress ('none', 'zoom', 'datapoint', 'map')
        GPSdata_clean % array of the solution data
        GPSdata_hover_Idx % the datapoint that the mouse currently hovers over
        GPSdata_raw % array of the original data
        GPSdata_selected % array to keep track of which datapoints are selected
        GPSdata_selected_Idx % the indices of the selected GPS datapoints
        SelectionRectangleEnd % end position of the selection rectangle
        SelectionRectangleStart % start position of the selectuon rectangle
        SelectionRectangleVisible logical = false; % show the selection rectangle (true/false)
        SelectionType % determines whether the new selection is added to the existing one (Shift behaviour) or the XOR is taken (Ctrl behaviour)
        ShiftKeyDown logical = false; % Whether the Shift key is pressed or not
        ToolTip2 % Shows the time value of the cursor position
        ZoomRectangleEnd % end position of the zoom rectangle
        ZoomRectangleStart % start location of the rectangle
        ZoomRectangleVisible logical = false; % Show the rectangle shape to indicate zooming range
        addartifactapp % connection to the add artifact window
        artifacts % struct with all artifacts ('start','end','solution','custom')
        cfg % The configuration struct that was passed by the parent app
        drawvalidationdata = 1;
        focus_lat % the lowest time value of a focussed window
        focus_lon % the highest time value of a focussed window
        history % all previous artifacts structs. Every time a change is made, it will be appended to this list. Once the undo button is pressed, it will remove the latest entry, and overwrite artifacts with the current last one.
        mode = 'focus'; % whether the current mode is in 'focus'  or 'all', determining the current visualization
        originalobject % the original data plot, later used for handling the brush
        selectedartifact % index of selected artifact
        time % float value for time
        timeResolution % distance between two timepoints
        visibleArtifactsRectangles % rectangles mapped on the axis
        visibleartifacts % array of 1s (true) and 0s (false) to keep track of which artifacts are visible
    end
    
    
    methods (Access = private)
        %% CALLBACKS

        % Callback when artifact is clicked on (NOTE: this is a public function in the app)
        function ClickArtifact(comp, object, eventdata)

        end
        
        % Callback when original data axis is clicked on
        function ClickAxes(comp, object, eventdata)

        end

        %% INTERACTIVITY

        % Interact with an Artifact
        function ArtifactInteraction(comp,artifactID)
            if comp.selectedartifact == artifactID
                % The selected artifact was deselected
                comp.selectedartifact = 0;
            else
                % an artifact that was not selected, is now selected
                comp.selectedartifact = artifactID;
            end
            comp.SetActiveUIElements();
            comp.RedrawArtifacts();
            comp.ShowSelectedArtifactNr();
            comp.PlotVisuals();
        end

        % Deselect all artifacts
        function DeselectAllArtifacts(comp)
            comp.selectedartifact = 0;
            comp.SetActiveUIElements();
            comp.RedrawArtifacts();
            comp.ShowSelectedArtifactNr();
            comp.PlotVisuals();

        end


        function new_selection = SelectClosestTo(comp,index)
            nof_artifacts = length(comp.artifacts);
            if nof_artifacts >= index
                % select the next artifact in the list
                new_selection = index;
            else
                % select the last artifact in the list
                new_selection = nof_artifacts;
            end
        end


        % Reverts the current artifacts to the previous state. Only
        % considers remove, add, merge, solution and duration changes.
        % Selection is not taken into account right now
        function UndoAction(comp)
            if length(comp.history) > 1
                comp.artifacts = comp.history(length(comp.history)-1).artifacts;
                comp.history(length(comp.history)) = [];
            else
                comp.RedoButton.Enable = 0;
            end

            comp.SetActiveUIElements();
            comp.RedrawArtifacts();
            comp.PlotVisuals();
            comp.SetActiveUIElements();
        end
        
        % add current state to the history list and enable the undo button
        function AddActionToHistory(comp)
            comp.history(length(comp.history)+1).artifacts = comp.artifacts;
            comp.RedoButton.Enable = true; 
        end


        % Focus on the selected artifact areas
        function FocusOnSelection(comp)
            mintime = min(comp.time);
            maxtime = max(comp.time);
            
            if comp.selectedartifact
                mintime = clamp(mintime,min(comp.time),max(comp.time));
                maxtime = clamp(maxtime,min(comp.time),max(comp.time));
    
                % determine which artifacts are within the focus interval
                comp.SetVisibleArtifacts(mintime, maxtime);
                comp.FocusOnArea(mintime,maxtime);
                comp.mode = 'focus';
                % comp.PlotVisuals();                
            end
        end

        function SetVisibleArtifacts(comp, mintime, maxtime)
            % intialize array of zeros to set visible to false for each artifactPlotArtifactValidation
            visible_artifacts = zeros(1, length(comp.artifacts));
            % then check for each whether the time ofthe artifact falls
            % within the specified time window
            for artifact_i=1:length(comp.artifacts)
                if (comp.artifacts(artifact_i).starttime > mintime && comp.artifacts(artifact_i).starttime < maxtime) ||...
                        (comp.artifacts(artifact_i).endtime > mintime && comp.artifacts(artifact_i).endtime < maxtime)
                    visible_artifacts(artifact_i) = 1;
                end
            end
            comp.visibleartifacts = visible_artifacts;
        end

        % Show the entire data signal
        function ShowAllData(comp)
            comp.mode = 'all';
            mintime = min(comp.time);
            maxtime = max(comp.time);
            
            % all artifacts are visible
            comp.SetVisibleArtifacts(mintime, maxtime);
            comp.RedrawArtifacts();
            comp.FocusOnArea(mintime,maxtime);
            comp.PlotVisuals();
            
            % set axes range
            min_lon = min(comp.GPSdata_clean.lon) - 0.001;
            min_lat = min(comp.GPSdata_clean.lat) - 0.001;
            max_lon = max(comp.GPSdata_clean.lon) + 0.001;
            max_lat = max(comp.GPSdata_clean.lat) + 0.001;
            geolimits(comp.GPSAxes, [min_lat max_lat], [min_lon max_lon]);
        end


        %% VISUALS
        % Reset the current visuals, and draw the latest visual state
        function PlotVisuals(comp)
            comp.PlotArtifactValidation();
        end

         % Draw the Artifact validation area 
        function PlotArtifactValidation(comp)

            % clear the graph area
            cla(comp.GPSAxes);

            % set the map type
            geobasemap(comp.GPSAxes, comp.cfg.basemap);

            % hold on = draw over the graph, keep what is already there
            hold(comp.GPSAxes,'on');

            % draw the artifact intervals
            comp.PlotArtifacts();

            % draw the original data
            geoplot(comp.GPSAxes, comp.GPSdata_raw.lat, comp.GPSdata_raw.lon,'LineStyle', ":", 'LineWidth', 1, 'Color', "k");

            % monitor whether the graph area is clicked on
            set(comp.GPSAxes,'ButtonDownFcn',@comp.ClickAxes);

            % draw the clean data
            geoplot(comp.GPSAxes, comp.GPSdata_clean.lat, comp.GPSdata_clean.lon, LineStyle = "--", LineWidth = 1, Color = "g", MarkerSize = 5, MarkerEdgeColor="g", Marker="o");

            % draw the zoom rectangle
            comp.drawZoomRectangle();           
            
            % draw the selection rectangle
            comp.drawSelectionRectangle();       

            % highlight the selected datapoints
            %comp.highlightSelectedData();
            BUasOrange =  [0.9294 0.4588 0.1294];
            geoplot(comp.GPSAxes, comp.GPSdata_clean.lat(comp.GPSdata_selected), comp.GPSdata_clean.lon(comp.GPSdata_selected), LineStyle = 'none', MarkerSize = 8, MarkerFaceColor = BUasOrange, MarkerEdgeColor = 'white', Marker='o');

            % hold off
            hold(comp.GPSAxes,'off');

            
        end


        function drawZoomRectangle(comp)
            if comp.ZoomRectangleVisible
                % Create and plot polygon
                lat1 = comp.ZoomRectangleStart(1, 1);
                lon1 = comp.ZoomRectangleStart(1, 2);
                lat2 = comp.ZoomRectangleEnd(1, 1);
                lon2 = comp.ZoomRectangleEnd(1, 2);

                poly = geopolyshape([lat1 lat2 lat2 lat1 lat1], [lon1 lon1 lon2 lon2 lon1]);
                BUasBlue = [0, 0.2510, 0.4196];
                geoplot(comp.GPSAxes, poly, 'FaceColor',  BUasBlue, 'FaceAlpha', 0.2, 'EdgeColor', BUasBlue)
            end
        end

        % zoom the GPS axes to the rectangle that was drawn
        function zoomToRectangle(comp)
            
            % hide the zoom rectangle
            comp.ZoomRectangleVisible = false;

            % MapCenter is the middle point of the rectangle that was drawn
            newMapCenterLat = (comp.ZoomRectangleStart(1, 1) + comp.ZoomRectangleEnd(1, 1)) / 2;
            newMapCenterLon = (comp.ZoomRectangleStart(1, 2) + comp.ZoomRectangleEnd(1, 2)) / 2;

            % for readability
            ax = comp.GPSAxes;

            % adjust the zoom level
            oldRangeLat = ax.LatitudeLimits(2) - ax.LatitudeLimits(1);
            oldRangeLon = ax.LongitudeLimits(2) - ax.LongitudeLimits(1);
            zoomRectangleRangeLat = abs(comp.ZoomRectangleEnd(1, 1) - comp.ZoomRectangleStart(1, 1));
            zoomRectangleRangeLon = abs(comp.ZoomRectangleEnd(1, 2) - comp.ZoomRectangleStart(1, 2));
            zoomFactorLat = oldRangeLat / zoomRectangleRangeLat;
            zoomFactorLon = oldRangeLon / zoomRectangleRangeLon;
            zoomFactor = min([zoomFactorLat, zoomFactorLon]);
            newZoomLevel = ax.ZoomLevel + log2(zoomFactor);
            if newZoomLevel > 25 % cannot exceed 25
                newZoomLevel = 25;
            end
            ax.ZoomLevel = newZoomLevel;

            % adjust the MapCenter
            ax.MapCenter(1) = newMapCenterLat;
            ax.MapCenter(2) = newMapCenterLon;

            % redraw
            comp.PlotVisuals();

        end

        % select all datapoints that are inside the rectangle that was drawn
        function selectDatapointsInRectangle(comp)
            
            % get the lat and lon dimensions of the rectangle
            minLat = min(comp.SelectionRectangleStart(1, 1), comp.SelectionRectangleEnd(1, 1));
            maxLat = max(comp.SelectionRectangleStart(1, 1), comp.SelectionRectangleEnd(1, 1));
            minLon = min(comp.SelectionRectangleStart(1, 2), comp.SelectionRectangleEnd(1, 2));
            maxLon = max(comp.SelectionRectangleStart(1, 2), comp.SelectionRectangleEnd(1, 2));

            % find all datapoints within these boundaries
            for data_i = 1:length(comp.GPSdata_clean.lat)
                if and(isbetween(comp.GPSdata_clean.lat(data_i), minLat, maxLat), ...
                        isbetween(comp.GPSdata_clean.lon(data_i), minLon, maxLon))
                    if strcmp(comp.SelectionType, 'extend')
                        % add the new selection to the existing one
                        comp.GPSdata_selected(data_i) = true;
                    elseif strcmp(comp.SelectionType, 'subtract')
                        % points in selection that were already selected get unselected
                        % those that were not, now get selected
                        comp.GPSdata_selected(data_i) = false;
                    end
                end

            end


            % redraw
            comp.PlotVisuals();

        end


        function drawSelectionRectangle(comp)
            if comp.SelectionRectangleVisible
                % Create and plot polygon
                lat1 = comp.SelectionRectangleStart(1, 1);
                lon1 = comp.SelectionRectangleStart(1, 2);
                lat2 = comp.SelectionRectangleEnd(1, 1);
                lon2 = comp.SelectionRectangleEnd(1, 2);

                poly = geopolyshape([lat1 lat2 lat2 lat1 lat1], [lon1 lon1 lon2 lon2 lon1]);
                BUasOrange =  [0.9294 0.4588 0.1294];
                geoplot(comp.GPSAxes, poly, 'FaceColor', BUasOrange, 'FaceAlpha', 0.2, 'EdgeColor',  BUasOrange)
            end
        end


        function highlightSelectedData(comp)
                geoplot(comp.GPSAxes, poly, 'FaceColor',  [0.9294 0.4588 0.1294], 'FaceAlpha', 0.2, 'EdgeColor',  [0.9294 0.4588 0.1294])
        end


        % Plot all rectangles where artifacts are suspected
        function PlotArtifacts(comp)
            for i=find(comp.visibleartifacts) % loop through the visible artifacts
                artifact_circle_width = 0.004;
                artifact_circle_left = comp.artifacts(i).lon - artifact_circle_width/2;
                artifact_circle_bottom = comp.artifacts(i).lat - artifact_circle_width/2;
                color = [.8 .8 .8 0.2];
                if comp.selectedartifact == i
                    color = [1 .8 .8 0.3];
                end
                comp.visibleArtifactsRectangles(i) = rectangle(comp.GPSAxes,'Position',[artifact_circle_left artifact_circle_bottom artifact_circle_width artifact_circle_width],"FaceColor",color,'Curvature',[1 1]);
                set(comp.visibleArtifactsRectangles(i),'ButtonDownFcn',@comp.ClickArtifact);
             end
        end

        % Only recreate the artifact visuals
        function RedrawArtifacts(comp)
            if isobject(comp.visibleArtifactsRectangles)
                delete(comp.visibleArtifactsRectangles);   
            end
            comp.visibleArtifactsRectangles=[];
            comp.PlotArtifacts();
        end

        % Set the visuals to focus on a specific area
        function FocusOnArea(comp,focus_lon, focus_lat)

            min_lon = focus_lon - 0.01;
            min_lat = focus_lat - 0.01;
            max_lon = focus_lon + 0.01;
            max_lat = focus_lat + 0.01;

            comp.focus_lat = focus_lon;
            comp.focus_lon = focus_lat;

            geolimits(comp.GPSAxes, [min_lon max_lon], [min_lat max_lat]);
        end

        %% UI ELEMENTS

        % Set the enabled UI elements based on the selected artifacts
        function SetActiveUIElements(comp)

            % % undo button
            % if (length(comp.history)) > 1
            %     comp.RedoButton.Enable = 1;
            % else
            %     comp.RedoButton.Enable = 0;                
            % end
            % % focus, previous, next
            % if ~isempty(comp.artifacts)
            %     comp.SelectAllButton.Enable = 1;
            %     comp.SelectNoneButton.Enable = 1;
            % else
            %     comp.SelectAllButton.Enable = 0;
            %     comp.SelectNoneButton.Enable = 0;
            % end
            % if (comp.selectedartifact <= 1)
            %     comp.SelectAllButton.Enable = 0;
            % end
            
        end

        % Disable the stop|skip|save buttons
        function DisableStopSkipSaveButtons(comp)
            comp.StopButton.Enable = "off";
            comp.SkipButton.Enable = "off";
            comp.SaveButton.Enable = "off";
        end

        
        % replace selected data points by NaNs
        function DeleteSelection(comp)

            % replace the selected data with NaNs
            comp.GPSdata_clean.lat(comp.GPSdata_selected) = NaN;
            comp.GPSdata_clean.lon(comp.GPSdata_selected) = NaN;
            comp.GPSdata_selected(comp.GPSdata_selected)  = false;
            
            % redraw
            comp.PlotVisuals();

        end

        % inerpolate selected data points where possible, replace by NaNs when interpolation is not possible
        function InterpolateSelection(comp)

            % split selected data into cluster os selected data points
            % first, find the indices where the selection changes from false to true (diff = 1) or the other way (diff = -1)
            d = diff(comp.GPSdata_selected);
            cluster_indices = [find([comp.GPSdata_selected(1);d]==1) find([d;-comp.GPSdata_selected(end)]==-1)];

            % for each cluster 
            for cluster_i = 1:size(cluster_indices, 1)
                % check whether there is a non-selected datapoint both before and after
                start_idx = cluster_indices(cluster_i, 1);
                end_idx   = cluster_indices(cluster_i, 2);
                if (    start_idx == 1  || ...                              % first datapoint was selected
                        end_idx == length(comp.GPSdata_raw.lat) || ...      % last datapoint was selected
                        isnan(comp.GPSdata_clean.lat(start_idx - 1)) || ... % the point before the selection is a NaN
                        isnan(comp.GPSdata_clean.lat(end_idx + 1)) ...      % the point after the selection is a NaN
                    )
                    
                    % interpolation not possible, replace by NaNs
                    comp.GPSdata_clean.lat(start_idx:end_idx) = NaN;
                    comp.GPSdata_clean.lon(start_idx:end_idx) = NaN;
                    comp.GPSdata_selected(start_idx:end_idx) = false;
                else

                    % interpolate the selcted points
                    % get the coordinates of the point before and after
                    % TODO: using the cleaned data as anchors. Is that the best choice?
                    before_lat = comp.GPSdata_clean.lat(start_idx - 1);
                    before_lon = comp.GPSdata_clean.lon(start_idx - 1);
                    after_lat = comp.GPSdata_clean.lat(end_idx + 1);
                    after_lon = comp.GPSdata_clean.lon(end_idx + 1);
                    range_lat = [before_lat, after_lat];
                    range_lon = [before_lon, after_lon];
                    
                    % get the number of selected points
                    nof_selected_points = end_idx - start_idx + 1;


                    % Interpolate along a great circle
                    [interpolated_lat, interpolated_lon] = gcwaypts(before_lat,before_lon,after_lat,after_lon,nof_selected_points-1); %interpm(range_lat, range_lon, nof_selected_points, 'gc');

                    % asign lat, lon values
                     comp.GPSdata_clean.lat(start_idx:end_idx) = interpolated_lat;
                     comp.GPSdata_clean.lon(start_idx:end_idx) = interpolated_lon;

                end

            % if not, then replace that batch by NaNs (interpolation not possible)

            % otherwise, interpolate 


            end

            % redraw
            comp.PlotVisuals();

        end


    end
    
    methods (Access = public)
        
        % clear the data from the axes
        function ClearData(comp)
            comp.GPSAxes.Title.String = '';
            comp.GPSdata_raw = [];
            comp.GPSdata_clean = [];
            comp.GPSdata_selected = [];
            % set UI Elements
            comp.SetActiveUIElements();
            % clear the graph area
            cla(comp.GPSAxes);
            % set initial axes
            % comp.PlotVisuals();
            pause(0.2); % wait 0.2 second to show that the previous data has been closed

        end
        
        % show the skin conductance data and potential artifacts
        function showData(comp, cfg, data)

            comp.cfg = cfg; % keep track of the cfg, we want to pass it back to the app

            % Show which segment and participant this is
            if isfield(cfg, 'segment_identifier')
                comp.GPSAxes.Title.String = cfg.segment_identifier;
            else
                comp.GPSAxes.Title.String = 'Warning: unknown segment';
            end

            % set the time variable
            if isfield(cfg, 'time')
                comp.time = cfg.time;
            else
                comp.time = 0:length(data)-1;
            end
            comp.timeResolution = comp.time(2)-comp.time(1);

            % create an array of the potential artifacts

            % the GPS data 
            comp.GPSdata_raw = data; % the raw data
            comp.GPSdata_clean = data; % the cleaned data

            % a list of boleans to keep track of which datapoints are selected
            comp.GPSdata_selected = false(size(data.lat));

                comp.ShowAllData();

            % set UI Elements
            comp.SetActiveUIElements();
            % set initial axes
            comp.PlotVisuals();

            % enable the stop|skip|save buttons
            comp.StopButton.Enable = "on";
            comp.SkipButton.Enable = "on";
            comp.SaveButton.Enable = "on";
            
        end
        
        % Handle mouse movements. This function is called by the parent app
        % input parameter is the cursor position
        % output is the type of mouse pointer
        function Pointertype = MouseMove(comp, cursorXY)
            
            % for readability
            ax = comp.GPSAxes;

            Pointertype = "arrow";

            % GeoAxes position measures are normalized by default, switching to pixels here
            ax.Units = 'pixels';

            % Get the Axis coordinates
            GPSAxes_left   = ax.InnerPosition(1);
            GPSAxes_right  = GPSAxes_left + ax.InnerPosition(3);
            GPSAxes_bottom = ax.InnerPosition(2);
            GPSAxes_top    = GPSAxes_bottom + ax.InnerPosition(4);

            % determine wheter mouse is over the GPS data area
            isInAxes = isbetween(cursorXY(1), GPSAxes_left, GPSAxes_right) && ...
                       isbetween(cursorXY(2), GPSAxes_bottom, GPSAxes_top);

            if isInAxes
                % when the mouse is moving over the GPS axes, one of these things is happening
                % 1) the mouse is not near a datapoint -> nothing to do, just moving around
                % 2) when the mouse is near a datapont -> highlight that point: size, colour
                % 3) if a zoom box is being dragged -> show the rectangle from the start until the current point
                % 4) if a datapoint is being dragged -> show the resulting data: line and markers

                % get the GPS coordinates of the current cursor position
                cursorPos = ax.CurrentPoint;

                % for debugging
                % comp.GPSAxes.Title.String = sprintf("lat = %d, lon = %d", cursorGPS(1, 1), cursorGPS(1, 2));

                % if a selected datapoint is in the process of being dragged
                if strcmp(comp.GPSAxesDragging, 'datapoint')
                    % update the position of the selected datapoint

                elseif strcmp(comp.GPSAxesDragging, 'zoom')

                    % Update the end location of the zoom rectangle
                    comp.ZoomRectangleEnd = cursorPos;

                    % redraw the map
                    comp.ZoomRectangleVisible = true;
                    comp.PlotVisuals();

                elseif strcmp(comp.GPSAxesDragging, 'select')

                    % Update the end location of the zoom rectangle
                    comp.SelectionRectangleEnd = cursorPos;

                    % redraw the map
                    comp.SelectionRectangleVisible = true;
                    comp.PlotVisuals();

                elseif strcmp(comp.GPSAxesDragging, 'map')
                    % MapCenter should move with the cursor

                    % calculate the dragging distance in XY pixels
                    CursorMovedX = cursorXY(1) - comp.DragMapStartXY(1);
                    CursorMovedY = cursorXY(2) - comp.DragMapStartXY(2);

                    % divide by the width/height of the GeoAxes and multiply by the width/height in latitude/longitude
                    CursorMovedLat = CursorMovedY / ax.InnerPosition(4) * (ax.LatitudeLimits(2) - ax.LatitudeLimits(1));
                    CursorMovedLon = CursorMovedX / ax.InnerPosition(3) * (ax.LongitudeLimits(2) - ax.LongitudeLimits(1));
                    
                    % Shift the center of the map by those amounts in the opposite direction
                    MapCenterLat = comp.DragMapStartMapCenter(1) - CursorMovedLat;
                    MapCenterLon = comp.DragMapStartMapCenter(2) - CursorMovedLon;
                    ax.MapCenter = [MapCenterLat, MapCenterLon];


                else % no dragging is going on

                    % test whether the cursor is near the border of the selected artifact interval
                    isNearBorder = false;
                    nearnessLimit = 10*comp.timeResolution; % TODO: increased this by factor 10 for the Shimmer data. This needs a general solution 
                    % check which artifact is selected
                    selectedArtifactIndex = comp.selectedartifact;

                    % check whether the cursor is near the borders of the selected artifact
                    if selectedArtifactIndex > 0
                        startpos = comp.artifacts(selectedArtifactIndex).starttime; % left border
                        endpos = comp.artifacts(selectedArtifactIndex).endtime; % right border
                        if abs(cursorTime - startpos) <= nearnessLimit
                            isNearBorder = true;
                            comp.GPSAxesDragging.LeftBorder = true;
                        elseif abs(cursorTime - endpos) <= nearnessLimit
                            isNearBorder = true;
                            comp.GPSAxesDragging.LeftBorder = false;
                        end
                        if isNearBorder
                            % change the cursor to the left-right dubbel arrow <->
                            % to indicate that the border can be dragged
                            % comp.BeltArtifactCorrectionAppUI.Pointer = "left";
                            Pointertype = "left";
                            % mark that cursor is near the border
                            comp.GPSAxesDragging.NearBorder = true;
                            % mark which artifact this is the border of
                            comp.GPSAxesDragging.Index = selectedArtifactIndex;
                        else
                            % change cursor back to the default arrow
                            % Pointertype = "arrow";
                            % and mark that cursor is not near a border
                            comp.GPSAxesDragging.NearBorder = false;
                        end
                    end
                end
            else
                % for debugging
                % comp.GPSAxes.Title.String = "mouse is not over the map";

                % the cursor has moved out of the map, stop dragging 
                comp.GPSAxesDragging = 'none'; 
            end
        
        end % MouseMove()


        % mouse up event is passed on by the parent app
        function MouseUp(comp)


            % Check if some kind of dragging was going on
            if strcmp(comp.GPSAxesDragging, 'zoom')

                % zoom to map to the rectangle
                comp.zoomToRectangle();
                
            elseif strcmp(comp.GPSAxesDragging, 'select')

                % hide the zoom rectangle
                comp.SelectionRectangleVisible = false;

                % select all datapoints that are within that rectangle
                comp.selectDatapointsInRectangle();
                
            elseif strcmp(comp.GPSAxesDragging, 'datapoint')
                % drop the datapoint at the current position
                %TODO add functionality to restore a dragged datapoint to its original position

             elseif strcmp(comp.GPSAxesDragging, 'map')
                % moving the map the map is handled by the MouseMove function
                % so nothing to do
           end

            % if the Shift key is pressed
            if comp.ShiftKeyDown

            end

            % stop any dragging functionality
            comp.GPSAxesDragging = 'none';

        end


        % mouse down event is passed on by the parent app
        function MouseDown(comp, cursorXY, selectionType)

            % for readability
            ax = comp.GPSAxes;

            % GeoAxes position measures are normalized by default, switching to pixels here
            ax.Units = 'pixels';

            % Get the Axis coordinates
            GPSAxes_left   = ax.InnerPosition(1);
            GPSAxes_right  = GPSAxes_left + ax.InnerPosition(3);
            GPSAxes_bottom = ax.InnerPosition(2);
            GPSAxes_top    = GPSAxes_bottom + ax.InnerPosition(4);

            % determine wheter mouse is over the GPS data area
            isInAxes = isbetween(cursorXY(1), GPSAxes_left, GPSAxes_right) && ...
                       isbetween(cursorXY(2), GPSAxes_bottom, GPSAxes_top);

            if isInAxes
                % for readability
                ax = comp.GPSAxes;
    
                comp.GPSAxes.Title.String =   sprintf('Current point lat = %s, lon = %s', string(comp.GPSAxes.CurrentPoint(1, 1)), string(comp.GPSAxes.CurrentPoint(1, 2))); 
    
                % was it a left or a right mouse click
                % we have to circumvent the normal MatLab handling here
                % Click left         => SelectionType = 'normal'
                % Click left + Shift => SelectionType = 'extend'
                % Click left + Ctrl  => SelectionType = 'alt'
                % Click right        => SelectionType = 'alt'
                % Double click any   => SelectionType = 'open'
                % So we deduce a right click from SelectionType = 'alt' and Ctrl was not pressed
                if and(strcmp(selectionType, 'alt'), ~comp.ControlKeyDown)
                    buttonType = 'right';
                elseif  strcmp(selectionType, 'open') 
                    buttonType = 'double';
                elseif strcmp(selectionType, 'normal') 
                    buttonType = 'left';
                else
                    buttonType = 'left';
                end

                if strcmp(buttonType, 'right')
                    % start drawing a zoom rectangle
                    comp.GPSAxesDragging = 'zoom';
                    comp.ZoomRectangleStart = ax.CurrentPoint;

                elseif strcmp(buttonType, 'left')
                    if comp.ShiftKeyDown
                        % start drawing a selection rectangle
                        comp.GPSAxesDragging = 'select';
                        comp.SelectionType = 'extend';
                        comp.SelectionRectangleStart = ax.CurrentPoint;

                    elseif comp.ControlKeyDown
                        % start drawing a selection rectangle
                        comp.GPSAxesDragging = 'select';
                        comp.SelectionType = 'subtract';
                        comp.SelectionRectangleStart = ax.CurrentPoint;

                    else
                        % start moving the map
                        comp.GPSAxesDragging = 'map';
                        comp.DragMapStartXY = cursorXY;
                        comp.DragMapStartMapCenter = ax.MapCenter;
                    end
                end
            end
            
        end

        % mouse scrollwheel event is passed on by the parent app
        function ScrollWheel(comp, VerticalScrollCount)
            
            % for readability
            ax = comp.GPSAxes;

            % get the new zoom level
            newZoomLevel = comp.GetNewZoomLevel(VerticalScrollCount);

            % calculate the change in zoomLevel
            % ZoomLevel is logarithmic: increase by 1 doubles the scale
            zoomChange = newZoomLevel - ax.ZoomLevel;
            zoomChangeFactor = 2^zoomChange;

            % adjust the MapCenter so that zooming keeps the map fixed at the cursor location
            % get the cursor position
            cursorLat = ax.CurrentPoint(1,1);
            cursorLon = ax.CurrentPoint(1,2);
            % get the MapCenter position
            mapCenterLat = ax.MapCenter(1);
            mapCenterLon = ax.MapCenter(2);
            % get the map width and height
            mapLatMin  = ax.LatitudeLimits(1);
            mapLatMax  = ax.LatitudeLimits(2);
            mapLatDiff = mapLatMax - mapLatMin;
            mapLonMin  = ax.LongitudeLimits(1);
            mapLonMax  = ax.LongitudeLimits(2);
            mapLonDiff = mapLonMax - mapLonMin;
            % calculate the cursor position as a percentage of the map
            percLat = (cursorLat - mapLatMin) / mapLatDiff;
            percLon = (cursorLon - mapLonMin) / mapLonDiff;
            % calculate the new lat/lon differences from cursor to mapcenter
            newMapLatDiff = mapLatDiff / zoomChangeFactor;
            newMapLonDiff = mapLonDiff / zoomChangeFactor;
            newMapLatMin = mapCenterLat - newMapLatDiff / 2;
            newMapLonMin = mapCenterLon - newMapLonDiff / 2;
            % the new cursor lat/lon would then be
            newCursorLat = newMapLatMin + percLat * newMapLatDiff;
            newCursorLon = newMapLonMin + percLon * newMapLonDiff;
            % but we want to keep the lat/lon the same for the cursor
            % so we will move the map
            newMapCenterLat = mapCenterLat + (cursorLat - newCursorLat);
            newMapCenterLon = mapCenterLon + (cursorLon - newCursorLon);            

            % set the new zoom level
            ax.ZoomLevel = newZoomLevel;

            % adjust the MapCenter
            ax.MapCenter(1) = newMapCenterLat;
            ax.MapCenter(2) = newMapCenterLon;

        end

        function newZoomLevel = GetNewZoomLevel(comp, zoomDirection)

            % for readability
            ax = comp.GPSAxes;

            % zoom faster if the Shift key is pressed
            % VerticalScrollCount = -1 or 1
            if comp.ShiftKeyDown
                ZoomChange = 1 * zoomDirection * -1;
            else
                ZoomChange = 0.2 * zoomDirection * -1;
            end

            % calculate the new zoom level
            newZoomLevel = ax.ZoomLevel + ZoomChange;

            % check ZoomLevel limits
            if newZoomLevel < 2 % Offical limit is 0, but location drifts for small numbers
                newZoomLevel = 2;
            elseif newZoomLevel > 25 % Offical limit is 25
                newZoomLevel = 25;
            end

        end

        function MoveMap(comp, direction)
            % TODO: if the focus is on the participant bookkeeping table,
            %       the arrow keys move the focus to another cell and trigger it's action.
            %       Will need to look for a way to prevent that.

            % for readability
            ax = comp.GPSAxes;

            % move faster if the Shift key is pressed
            if comp.ShiftKeyDown
                MoveAmount = 0.001;
            else
                MoveAmount = 0.0002;
            end

            switch direction
                case 'left' % move in longitudinal direction
                    ax.MapCenter(2) = ax.MapCenter(2) + MoveAmount; 
                case 'right' % move in longitudinal direction
                    ax.MapCenter(2) = ax.MapCenter(2) - MoveAmount;
                case 'up' % move in latitudinal direction
                    ax.MapCenter(1) = ax.MapCenter(1) - MoveAmount;
                case 'down' % move in latitudinal direction
                    ax.MapCenter(1) = ax.MapCenter(1) + MoveAmount;
            end

        end

        % keypress is passed on by the parent app
        function KeyPress(comp, Key)
            switch Key 
                case 'shift'
                    comp.ShiftKeyDown = true;
                case 'control'
                    comp.ControlKeyDown = true;
                case 'add' % plus sign -> zoom in
                    comp.ZoomInButtonPushed();
                case 'subtract' % minus sign -> zoom out
                    comp.ZoomOutButtonPushed();
                case 'space'  % zoom to show all data
                    comp.ZoomHomeButtonPushed();
                case 'leftarrow'  % move the map to the left
                    comp.MoveMap('left');
                case 'rightarrow'  % move the map to the right
                    comp.MoveMap('right');
                case 'uparrow'  % move the map up
                    comp.MoveMap('up');
                case 'downarrow'  % move the map down
                    comp.MoveMap('down');
                case 'delete'  % delete or interpolate selected data
                    if comp.ShiftKeyDown
                        comp.DeleteSelection();
                    else
                        comp.InterpolateSelection();
                    end
                        
            end
        end

        % keyrelease is passed on by the parent app
        function KeyRelease(comp, Key)
            switch Key 
                case 'shift'
                    comp.ShiftKeyDown = false;
                case 'control'
                    comp.ControlKeyDown = false;
            end
        end


    end % methods


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function nof_(comp)
          
            comp.GPSAxesDragging = 'none'; 
            drawnow;

            % Create geographic axes
            comp.GPSAxes = geoaxes(comp.GridLayout, ...
                NextPlot = "add", ...
                Basemap = "streets-light", ...
                MapCenter = [51.590548764358566, 4.795605396225078], ...
                ZoomLevel = 15);
            % Plot a location pin at BUas Experience Lab
            geoplot(comp.GPSAxes, 51.590548764358566, 4.795605396225078, 'Color', [0.9333 0.4627 0.1333], 'Marker', "*", 'MarkerSize', 10, 'LineWidth', 2);
            % panning and zooming
            comp.GPSAxes.Interactions = [panInteraction zoomInteraction];
            
            % Add title
            comp.GPSAxes.Title.String = "BUas Experience Lab";

        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(comp, event)
            % select each data point
            %TODO: how to handle NaN position points?
            for data_i = 1:length(comp.GPSdata_clean.lat)
                comp.GPSdata_selected(data_i) = true;
            end
            % redraw
            comp.PlotVisuals();
        end

        % Button pushed function: SelectNoneButton
        function SelectNoneButtonPushed(comp, event)
            % deselect each data point
            for data_i = 1:length(comp.GPSdata_clean.lat)
                comp.GPSdata_selected(data_i) = false;
            end
            % redraw
            comp.PlotVisuals();
        end

        % Button pushed function: RestoreAllButton
        function RestoreAllButtonPushed(comp, event)
            % restore all of the clean data back to the original data
            comp.GPSdata_clean = comp.GPSdata_raw;
            % redraw
            comp.PlotVisuals();
        end

        % Button pushed function: RestoreSelectionButton
        function RestoreSelectionButtonPushed(comp, event)
            % restore the selected clean data back to the original data
            comp.GPSdata_clean(comp.GPSdata_selected) = comp.GPSdata_raw(comp.GPSdata_selected);
            % redraw
            comp.PlotVisuals();
        end

        % Button pushed function: ZoomInButton
        function ZoomInButtonPushed(comp, event)
            
            % for readability
            ax = comp.GPSAxes;

            % get the new zoom level
            zoomDirection = -1; % zoom in
            newZoomLevel = comp.GetNewZoomLevel(zoomDirection);

            % set the new zoom level
            ax.ZoomLevel = newZoomLevel;

        end

        % Button pushed function: ZoomOutButton
        function ZoomOutButtonPushed(comp, event)
            
            % for readability
            ax = comp.GPSAxes;

            % get the new zoom level
            zoomDirection = 1; % zoom out
            newZoomLevel = comp.GetNewZoomLevel(zoomDirection);

            % set the new zoom level
            ax.ZoomLevel = newZoomLevel;

        end

        % Button pushed function: ZoomHomeButton
        function ZoomHomeButtonPushed(comp, event)
            comp.ShowAllData();
        end

        % Button pushed function: DeleteSelectionButton
        function DeleteSelectionButtonPushed(comp, event)
            % replace the selected data with NaNs
            comp.DeleteSelection();
        end

        % Button pushed function: InterpolateSelectionButton
        function InterpolateSelectionButtonPushed(comp, event)
            % interpolate the selected data if possible, otherwise replace with NaNs
            comp.InterpolateSelection();
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(comp, event)
            
        end

        % Button pushed function: RedoButton
        function RedoButtonPushed(comp, event)
            comp.UndoAction();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(comp, event)
            notify(comp, 'StopArtifactCorrection');
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(comp, event)
            comp.GPSdata_clean = comp.GPSdata_raw;
            notify(comp, 'SkipArtifactCorrection');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(comp, event)
            % disable the stop, skip, and save buttons
            comp.DisableStopSkipSaveButtons();
            % Let the BELT app know what happened
            notify(comp, 'SaveArtifactCorrectedData');
        end

        % Button pushed function: JumpsButton
        function JumpsButtonPushed(comp, event)
            % Find and select all GPS points that have a jump that is larger than the specified limit

            % read the limit value
            jump_limit = comp.JumpLimit.Value;

            % calculate the distances from every point to the next.
            wgs84 = wgs84Ellipsoid("m"); % Using WGS84 ellipsoid (GPS) with unit meters
            
            % get the number of GPS points
            nof_gps_points = length(comp.GPSdata_raw.lat);

            % the distance with the previous point is 0 for the first point
            comp.GPSdata_raw.dist(1) = 0;
            % for each point calculate the distance with the previous point
            comp.GPSdata_raw.dist(2:nof_gps_points) = distance(...
                comp.GPSdata_raw.lat(1:nof_gps_points-1), ...
                comp.GPSdata_raw.lon(1:nof_gps_points-1), ...
                comp.GPSdata_raw.lat(2:nof_gps_points), ...
                comp.GPSdata_raw.lon(2:nof_gps_points), wgs84);

            % select all points for which the distance exceeds that limit
            comp.GPSdata_selected = comp.GPSdata_raw.dist > jump_limit;

            % show the selection
            comp.PlotVisuals();

            
        end

        % Button pushed function: AccuracyButton
        function AccuracyButtonPushed(comp, event)
            % Find and select all GPS points that have an accuracy value that is higher than the specified limit

            % read the limit value
            accuracy_limit = comp.AccuracyLimit.Value;

            % select all points for which the accuracy exceeds that limit
            comp.GPSdata_selected = comp.GPSdata_raw.acc > accuracy_limit;

            % show the selection
            comp.PlotVisuals();

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 1028 598];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.GridLayout2.Tooltip = {''};
            comp.SelectionPanel = uipanel(comp.GridLayout2);
            comp.SelectionPanel.Title = 'Selection';
            comp.SelectAllButton = uibutton(comp.SelectionPanel, 'push');
            comp.SelectAllButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SelectAllButtonPushed, true);
            comp.SelectAllButton.Tooltip = {'Select all data points'};
            comp.SelectAllButton.Text = 'Select all';
            comp.SelectNoneButton = uibutton(comp.SelectionPanel, 'push');
            comp.SelectNoneButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SelectNoneButtonPushed, true);
            comp.SelectNoneButton.Tooltip = {'Deselect all '};
            comp.SelectNoneButton.Text = 'Select none';
            comp.RestoreSelectionButton = uibutton(comp.SelectionPanel, 'push');
            comp.RestoreSelectionButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RestoreSelectionButtonPushed, true);
            comp.RestoreSelectionButton.Tooltip = {'Restore all selected data points to their original position'};
            comp.RestoreSelectionButton.Text = 'Restore selection';
            comp.RestoreAllButton = uibutton(comp.SelectionPanel, 'push');
            comp.RestoreAllButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RestoreAllButtonPushed, true);
            comp.RestoreAllButton.Tooltip = {'Restore all data points to their original position'};
            comp.RestoreAllButton.Text = 'Restore all';
            comp.ConclusionPanel = uipanel(comp.GridLayout2);
            comp.ConclusionPanel.Title = 'Conclusion';
            comp.GridLayout6 = uigridlayout(comp.ConclusionPanel);
            comp.SkipButton = uibutton(comp.GridLayout6, 'push');
            comp.SkipButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SkipButtonPushed, true);
            comp.SkipButton.Icon = fullfile(pathToMLAPP, 'icons', 'skipWhite.png');
            comp.SkipButton.Enable = 'off';
            comp.SkipButton.Tooltip = {'Skip to the next participant'};
            comp.SkipButton.Text = '';
            comp.SaveButton = uibutton(comp.GridLayout6, 'push');
            comp.SaveButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SaveButtonPushed, true);
            comp.SaveButton.Icon = fullfile(pathToMLAPP, 'icons', 'saveWhite.png');
            comp.SaveButton.Enable = 'off';
            comp.SaveButton.Tooltip = {'Save clean GPS data'};
            comp.SaveButton.Text = '';
            comp.StopButton = uibutton(comp.GridLayout6, 'push');
            comp.StopButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @StopButtonPushed, true);
            comp.StopButton.Icon = fullfile(pathToMLAPP, 'icons', 'stopWhite.png');
            comp.StopButton.Enable = 'off';
            comp.StopButton.Tooltip = {'Stop GPS cleaning'};
            comp.StopButton.Text = '';
            comp.ActionsPanel_2 = uipanel(comp.GridLayout2);
            comp.ActionsPanel_2.Title = 'Actions';
            comp.GridLayout5 = uigridlayout(comp.ActionsPanel_2);
            comp.RedoButton = uibutton(comp.GridLayout5, 'push');
            comp.RedoButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RedoButtonPushed, true);
            comp.RedoButton.Icon = fullfile(pathToMLAPP, 'icons', 'redoWhite.png');
            comp.RedoButton.Tooltip = {'Redo last action'};
            comp.RedoButton.Text = '';
            comp.UndoButton = uibutton(comp.GridLayout5, 'push');
            comp.UndoButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @UndoButtonPushed, true);
            comp.UndoButton.Icon = fullfile(pathToMLAPP, 'icons', 'undoWhite.png');
            comp.UndoButton.Tooltip = {'Undo last action'};
            comp.UndoButton.Text = '';
            comp.InterpolateSelectionButton = uibutton(comp.GridLayout5, 'push');
            comp.InterpolateSelectionButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @InterpolateSelectionButtonPushed, true);
            comp.InterpolateSelectionButton.Icon = fullfile(pathToMLAPP, 'icons', 'interpolateWhite.png');
            comp.InterpolateSelectionButton.Tooltip = {'Interpolate selected points'};
            comp.InterpolateSelectionButton.Text = '';
            comp.DeleteSelectionButton = uibutton(comp.GridLayout5, 'push');
            comp.DeleteSelectionButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @DeleteSelectionButtonPushed, true);
            comp.DeleteSelectionButton.Icon = fullfile(pathToMLAPP, 'icons', 'deleteWhite.png');
            comp.DeleteSelectionButton.Tooltip = {'Delete selected points'};
            comp.DeleteSelectionButton.Text = '';
            comp.ZoomPanel = uipanel(comp.GridLayout2);
            comp.ZoomPanel.Title = 'Zoom';
            comp.GridLayout4 = uigridlayout(comp.ZoomPanel);
            comp.ZoomHomeButton = uibutton(comp.GridLayout4, 'push');
            comp.ZoomHomeButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ZoomHomeButtonPushed, true);
            comp.ZoomHomeButton.Icon = fullfile(pathToMLAPP, 'icons', 'zoomHomeWhite.png');
            comp.ZoomHomeButton.Tooltip = {'Zoom to fit all'};
            comp.ZoomHomeButton.Text = '';
            comp.ZoomOutButton = uibutton(comp.GridLayout4, 'push');
            comp.ZoomOutButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ZoomOutButtonPushed, true);
            comp.ZoomOutButton.Icon = fullfile(pathToMLAPP, 'icons', 'zoomOutWhite.png');
            comp.ZoomOutButton.Tooltip = {'Zoom out'};
            comp.ZoomOutButton.Text = '';
            comp.ZoomInButton = uibutton(comp.GridLayout4, 'push');
            comp.ZoomInButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ZoomInButtonPushed, true);
            comp.ZoomInButton.Icon = fullfile(pathToMLAPP, 'icons', 'zoomInWhite.png');
            comp.ZoomInButton.Tooltip = {'Zoom in'};
            comp.ZoomInButton.Text = '';
            comp.FindPanel = uipanel(comp.GridLayout2);
            comp.FindPanel.Title = 'Find';
            comp.JumpsButton = uibutton(comp.FindPanel, 'push');
            comp.JumpsButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JumpsButtonPushed, true);
            comp.JumpsButton.Tooltip = {'Select all data points'};
            comp.JumpsButton.Text = 'Jumps >';
            comp.AccuracyButton = uibutton(comp.FindPanel, 'push');
            comp.AccuracyButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @AccuracyButtonPushed, true);
            comp.AccuracyButton.Tooltip = {'Deselect all '};
            comp.AccuracyButton.Text = 'Accuracy >';
            comp.Label = uilabel(comp.FindPanel);
            comp.Label.Text = 'meter';
            comp.JumpLimit = uieditfield(comp.FindPanel, 'numeric');
            comp.JumpLimit.Limits = [0 200];
            comp.JumpLimit.ValueDisplayFormat = '%.0f';
            comp.JumpLimit.Value = 25;
            comp.meterEditField_2Label = uilabel(comp.FindPanel);
            comp.meterEditField_2Label.Text = 'meter';
            comp.AccuracyLimit = uieditfield(comp.FindPanel, 'numeric');
            comp.AccuracyLimit.Limits = [0 200];
            comp.AccuracyLimit.ValueDisplayFormat = '%.0f';
            comp.AccuracyLimit.Value = 25;
            comp.AnimatePanel = uipanel(comp.GridLayout2);
            comp.AnimatePanel.Title = 'Animate';
            comp.GridLayout3 = uigridlayout(comp.AnimatePanel);
            comp.RewindButton = uibutton(comp.GridLayout3, 'push');
            comp.RewindButton.Icon = fullfile(pathToMLAPP, 'icons', 'rewindWhiteLarge.png');
            comp.RewindButton.Tooltip = {'Rewind 1 minute'};
            comp.RewindButton.Text = '';
            comp.PauseButton = uibutton(comp.GridLayout3, 'push');
            comp.PauseButton.Icon = fullfile(pathToMLAPP, 'icons', 'pauseWhiteLarge.png');
            comp.PauseButton.Tooltip = {'Pause animation'};
            comp.PauseButton.Text = '';
            comp.StopAnimationButton = uibutton(comp.GridLayout3, 'push');
            comp.StopAnimationButton.Icon = fullfile(pathToMLAPP, 'icons', 'stopWhiteLarge.png');
            comp.StopAnimationButton.Tooltip = {'Stop animation'};
            comp.StopAnimationButton.Text = '';
            comp.PlayButton = uibutton(comp.GridLayout3, 'push');
            comp.PlayButton.Icon = fullfile(pathToMLAPP, 'icons', 'playWhiteLarge.png');
            comp.PlayButton.Tooltip = {'Play animation'};
            comp.PlayButton.Text = '';
            comp.FastForwardButton = uibutton(comp.GridLayout3, 'push');
            comp.FastForwardButton.Icon = fullfile(pathToMLAPP, 'icons', 'fastforwardWhiteLarge.png');
            comp.FastForwardButton.Tooltip = {'Fast forward 1 minute'};
            comp.FastForwardButton.Text = '';
            comp.FasterButton = uibutton(comp.GridLayout3, 'push');
            comp.FasterButton.Icon = fullfile(pathToMLAPP, 'icons', 'plusWhiteSmall.png');
            comp.FasterButton.Tooltip = {'Faster'};
            comp.FasterButton.Text = '';
            comp.SlowerButton = uibutton(comp.GridLayout3, 'push');
            comp.SlowerButton.Icon = fullfile(pathToMLAPP, 'icons', 'minusWhiteSmall.png');
            comp.SlowerButton.Tooltip = {'Slower'};
            comp.SlowerButton.Text = '';
            % Execute the startup function
            nof_(comp)
        end
    end
end