classdef CreateNewProjectApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CancelButton                    matlab.ui.control.Button
        CreateButton                    matlab.ui.control.Button
        SelectProjectDirectoryButton    matlab.ui.control.Button
        ProjectdirectoryEditField       matlab.ui.control.EditField
        ProjectdirectoryEditFieldLabel  matlab.ui.control.Label
        ProjectnameEditField            matlab.ui.control.EditField
        ProjectnameEditFieldLabel       matlab.ui.control.Label
        CheckdirectoriesButtonGroup     matlab.ui.container.ButtonGroup
        AskmewhattodoforeachdirectorythatdoesnotexistButton  matlab.ui.control.RadioButton
        AutomaticallycreatedirectoriesthatdonotexistButton  matlab.ui.control.RadioButton
        DirectoriesPanel                matlab.ui.container.Panel
        SelectOutputDirectoryButton     matlab.ui.control.Button
        SelectProcessedDataDirectoryButton  matlab.ui.control.Button
        SelectRawDataDirectoryButton    matlab.ui.control.Button
        OutputEditField                 matlab.ui.control.EditField
        OutputEditFieldLabel            matlab.ui.control.Label
        ProcessedDataEditField          matlab.ui.control.EditField
        ProcesseddataLabel              matlab.ui.control.Label
        RawdataEditField                matlab.ui.control.EditField
        RawdataEditFieldLabel           matlab.ui.control.Label
        CreateanewprojectLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        closeapplication = 0; % boolean value for the main application to return when set to true
        save_project     = 0; % boelean, set to true if the save button is pressed
        cancel_project   = 0; % boelean, set to true if the cancel button is pressed
        project = struct('project_name', "", ...
            'project_directory', "", ...
            'raw_data_directory', "", ...
            'processed_data_directory', "", ...
            'output_directory', "");
        cfg = struct('output_directory', "", ...
            'ask_create_directory', 'create');
    end
    
    methods (Access = private)
               
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, cfg, project)
            % intialize struct to hold project properties
            app.project.project_name = '';
            app.project.project_directory = '';
            app.project.raw_data_directory = '';
            app.project.processed_data_directory = '';
            app.project.output_directory = '';
            app.cfg.ask_create_directory = 'create';

            
            % if the app is called with directory specifications, 
            % start the input fields with these directories
            if isfield(project, 'project_name')
                app.project.project_name = project.project_name;
            end
            if isfield(project, 'project_directory')
                app.project.project_directory = project.project_directory;
                % also fill in the default structure
                projectfolder                                 = app.project.project_directory;
                app.project.raw_data_directory                 = fullfile(projectfolder, '0.RawData');
                app.project.processed_data_directory          = fullfile(projectfolder, '2.ProcessedData');
                app.project.output_directory                  = fullfile(projectfolder, '3.Output');
            end
            if isfield(project, 'raw_data_directory')
                app.project.raw_data_directory = project.raw_data_directory;
            end
            if isfield(project, 'processed_data_directory')
                app.project.processed_data_directory = project.processed_data_directory;
            end
            if isfield(project, 'output_directory')
                app.project.output_directory = project.output_directory;
            end
            if isfield(cfg, 'ask_create_directory')
                app.cfg.ask_create_directory = cfg.ask_create_directory;
            end

            % show these value in the input fields
            app.ProjectnameEditField.Value = app.project.project_name;
            app.ProjectdirectoryEditField.Value = app.project.project_directory;
            app.RawdataEditField.Value = app.project.raw_data_directory;
            app.ProcessedDataEditField.Value = app.project.processed_data_directory;
            app.OutputEditField.Value = app.project.output_directory;
            if strcmp(app.cfg.ask_create_directory, 'create')
                app.AutomaticallycreatedirectoriesthatdonotexistButton.Value = 1;
            elseif strcmp(app.cfg.ask_create_directory, 'ask')
                app.AskmewhattodoforeachdirectorythatdoesnotexistButton.Value = 1;
            end


        end

        % Button pushed function: SelectProjectDirectoryButton
        function SelectProjectDirectoryButtonPushed(app, event)
            tempdir = uigetdir(app.ProjectdirectoryEditField.Value, 'Project directory');
            figure(app.UIFigure); % retrun the focus to this window
            if tempdir
                app.ProjectdirectoryEditField.Value = tempdir;
                % update the other directories to the preferred structure
                projectfolder = app.ProjectdirectoryEditField.Value;
                app.RawdataEditField.Value = fullfile(projectfolder, '0.RawData');
                app.ProcessedDataEditField.Value = fullfile(projectfolder, '2.ProcessedData');
                app.OutputEditField.Value = fullfile(projectfolder, '3.Output');
            end
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            % Update the project struct
            app.project.project_name = app.ProjectnameEditField.Value;
            app.project.project_directory = app.ProjectdirectoryEditField.Value;
            app.project.raw_data_directory = app.RawdataEditField.Value;
            app.project.processed_data_directory = app.ProcessedDataEditField.Value;
            app.project.output_directory = app.OutputEditField.Value;
            if app.AskmewhattodoforeachdirectorythatdoesnotexistButton.Value
                app.cfg.ask_create_directory = 'ask';
            else
                app.cfg.ask_create_directory = 'create';
            end

            % Update the bookkeeping (.belt) file;

            % Let the main script know that the project should be saved
            app.save_project = 1; % save = true
            app.closeapplication = 1; % close app = true
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % close the app, project will not be saved
            app.closeapplication = 1; % close app = true
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % close the app, project will not be saved
            app.closeapplication = 1; % close app = true
        end

        % Button pushed function: SelectOutputDirectoryButton
        function SelectOutputDirectoryButtonPushed(app, event)
            tempdir = uigetdir(app.OutputEditField.Value, 'Output directory');
            figure(app.UIFigure); % retrun the focus to this window
            if tempdir
                app.OutputEditField.Value = tempdir;
            end
        end

        % Button pushed function: SelectProcessedDataDirectoryButton
        function SelectProcessedDataDirectoryButtonPushed(app, event)
            tempdir = uigetdir(app.ProcessedDataEditField.Value, 'Processed data directory');
            figure(app.UIFigure); % retrun the focus to this window
            if tempdir
                app.ProcessedDataEditField.Value = tempdir;
            end
        end

        % Button pushed function: SelectRawDataDirectoryButton
        function SelectRawDataDirectoryButtonPushed(app, event)
            tempdir = uigetdir(app.RawdataEditField.Value, 'Raw data directory');
            figure(app.UIFigure); % retrun the focus to this window
            if tempdir
                app.RawdataEditField.Value = tempdir;
            end
        end

        % Value changed function: ProjectdirectoryEditField
        function ProjectdirectoryEditFieldValueChanged(app, event)
            % The project directory was changed by hand.
            % Update the other directories to the preferred structure
            projectfolder = app.ProjectdirectoryEditField.Value;
            app.RawdataEditField.Value = fullfile(projectfolder, '0.RawData');
            app.ProcessedDataEditField.Value = fullfile(projectfolder, '2.ProcessedData');
            app.OutputEditField.Value = fullfile(projectfolder, '3.Output');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CreateanewprojectLabel = uilabel(app.UIFigure);
            app.CreateanewprojectLabel.Text = 'Create a new project';
            app.DirectoriesPanel = uipanel(app.UIFigure);
            app.DirectoriesPanel.Title = 'Directories';
            app.RawdataEditFieldLabel = uilabel(app.DirectoriesPanel);
            app.RawdataEditFieldLabel.Text = 'Raw data';
            app.RawdataEditField = uieditfield(app.DirectoriesPanel, 'text');
            app.ProcesseddataLabel = uilabel(app.DirectoriesPanel);
            app.ProcesseddataLabel.Text = 'Processed data';
            app.ProcessedDataEditField = uieditfield(app.DirectoriesPanel, 'text');
            app.OutputEditFieldLabel = uilabel(app.DirectoriesPanel);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.DirectoriesPanel, 'text');
            app.SelectRawDataDirectoryButton = uibutton(app.DirectoriesPanel, 'push');
            app.SelectRawDataDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRawDataDirectoryButtonPushed, true);
            app.SelectRawDataDirectoryButton.Text = 'Select';
            app.SelectProcessedDataDirectoryButton = uibutton(app.DirectoriesPanel, 'push');
            app.SelectProcessedDataDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectProcessedDataDirectoryButtonPushed, true);
            app.SelectProcessedDataDirectoryButton.Text = 'Select';
            app.SelectOutputDirectoryButton = uibutton(app.DirectoriesPanel, 'push');
            app.SelectOutputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputDirectoryButtonPushed, true);
            app.SelectOutputDirectoryButton.Text = 'Select';
            app.CheckdirectoriesButtonGroup = uibuttongroup(app.UIFigure);
            app.CheckdirectoriesButtonGroup.Title = 'Check directories';
            app.AutomaticallycreatedirectoriesthatdonotexistButton = uiradiobutton(app.CheckdirectoriesButtonGroup);
            app.AutomaticallycreatedirectoriesthatdonotexistButton.Text = 'Automatically create directories that do not exist';
            app.AutomaticallycreatedirectoriesthatdonotexistButton.Value = true;
            app.AskmewhattodoforeachdirectorythatdoesnotexistButton = uiradiobutton(app.CheckdirectoriesButtonGroup);
            app.AskmewhattodoforeachdirectorythatdoesnotexistButton.Text = 'Ask me what to do for each directory that does not exist';
            app.ProjectnameEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectnameEditFieldLabel.Text = 'Project name';
            app.ProjectnameEditField = uieditfield(app.UIFigure, 'text');
            app.ProjectdirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectdirectoryEditFieldLabel.Text = 'Project directory';
            app.ProjectdirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.ProjectdirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectdirectoryEditFieldValueChanged, true);
            app.SelectProjectDirectoryButton = uibutton(app.UIFigure, 'push');
            app.SelectProjectDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectProjectDirectoryButtonPushed, true);
            app.SelectProjectDirectoryButton.Text = 'Select';
            app.CreateButton = uibutton(app.UIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CreateNewProjectApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end